classdef WHISIT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Tab                            matlab.ui.container.TabGroup
        Analysis_Tab                   matlab.ui.container.Tab
        BFstackLabel                   matlab.ui.control.Label
        t1_Edit_BF_Fold                matlab.ui.control.EditField
        Ch1stackLabel                  matlab.ui.control.Label
        t1_Load_BF                     matlab.ui.control.Button
        t1_Alg_Panel                   matlab.ui.container.Panel
        t1_Alg_Radio                   matlab.ui.container.ButtonGroup
        Memb_2_PoleButton              matlab.ui.control.RadioButton
        Polar_SigButton                matlab.ui.control.RadioButton
        AvgSignalButton                matlab.ui.control.RadioButton
        LateralMembsizeLabel           matlab.ui.control.Label
        t1_Edit_M2P_LatMemb            matlab.ui.control.NumericEditField
        MembsizeLabel                  matlab.ui.control.Label
        t1_Edit_PL_Memb                matlab.ui.control.NumericEditField
        PoleMembsizeLabel_2            matlab.ui.control.Label
        t1_Edit_PL_PoleMemb            matlab.ui.control.NumericEditField
        RFactorLabel                   matlab.ui.control.Label
        t1_Edit_PL_PoleRad             matlab.ui.control.NumericEditField
        t1_Check_AxisProfile           matlab.ui.control.CheckBox
        PoleMembsizeLabel              matlab.ui.control.Label
        t1_Edit_M2P_PoleMemb           matlab.ui.control.NumericEditField
        PoleRadiusLabel                matlab.ui.control.Label
        t1_Edit_M2P_PoleRad            matlab.ui.control.NumericEditField
        ScaleFactorLabel               matlab.ui.control.Label
        t1_Edit_PL_ScaleFact           matlab.ui.control.NumericEditField
        t1_Edit_AxisWidth              matlab.ui.control.NumericEditField
        t1_Check_Segmentation          matlab.ui.control.CheckBox
        t1_Edit_SegmentWidth           matlab.ui.control.NumericEditField
        t1_Label_PerimSpacing          matlab.ui.control.Label
        t1_Edit_PerimeterSpacing       matlab.ui.control.NumericEditField
        t1_Edit_PerimeterWidth         matlab.ui.control.NumericEditField
        t1_Check_PerimeterSig          matlab.ui.control.CheckBox
        t1_ProfileRadioGroup           matlab.ui.container.ButtonGroup
        meanvalue_Radio                matlab.ui.control.RadioButton
        maxvalue_Radio                 matlab.ui.control.RadioButton
        START_t1                       matlab.ui.control.Button
        STOP_t1                        matlab.ui.control.Button
        BoxborderpxEditFieldLabel      matlab.ui.control.Label
        t1_Edit_BorderBox              matlab.ui.control.NumericEditField
        t1_ShowPlot                    matlab.ui.control.CheckBox
        SaveasLabel                    matlab.ui.control.Label
        t1_Exp_Title                   matlab.ui.control.EditField
        t1_Edit_WaitPlot               matlab.ui.control.NumericEditField
        Ch2stackLabel                  matlab.ui.control.Label
        t1_Edit_CH2_Fold               matlab.ui.control.EditField
        t1_Edit_CH1_Fold               matlab.ui.control.EditField
        t1_Load_CH2                    matlab.ui.control.Button
        t1_Load_CH1                    matlab.ui.control.Button
        t1_CheckBox_CH2                matlab.ui.control.CheckBox
        t1_Check_CH3_Marker            matlab.ui.control.CheckBox
        t1_Load_Det                    matlab.ui.control.Button
        DetmatLabel_2                  matlab.ui.control.Label
        t1_Edit_Det_file               matlab.ui.control.EditField
        t1_Load_Res_D2T                matlab.ui.control.Button
        Res_D2TmatLabel                matlab.ui.control.Label
        t1_Edit_Res_D2T                matlab.ui.control.EditField
        t1_Create_cloneList            matlab.ui.control.Button
        t1_CheckBox_CreateCloneList    matlab.ui.control.CheckBox
        t1_CheckBox_CH3                matlab.ui.control.CheckBox
        Ch3stackLabel                  matlab.ui.control.Label
        t1_Edit_CH3_Fold               matlab.ui.control.EditField
        t1_Load_CH3                    matlab.ui.control.Button
        t1_CheckBox_BF                 matlab.ui.control.CheckBox
        Lineage_Tab                    matlab.ui.container.Tab
        t3_Load_cloneList              matlab.ui.control.Button
        clonesmatfileLabel             matlab.ui.control.Label
        t3_Edit_cloneList              matlab.ui.control.EditField
        PLOT_t3                        matlab.ui.control.Button
        SizeborderlineLabel            matlab.ui.control.Label
        t3_EditField_Borderline        matlab.ui.control.NumericEditField
        LinethicknessLabel             matlab.ui.control.Label
        t3_EditField_LineSize          matlab.ui.control.NumericEditField
        DistancebtwlinesLabel          matlab.ui.control.Label
        t3_EditField_LinesDistance     matlab.ui.control.NumericEditField
        t3_CheckBox_DisplayPoles       matlab.ui.control.CheckBox
        t3_DropDown_LUT_CH2            matlab.ui.control.DropDown
        frameXminLabel                 matlab.ui.control.Label
        t3_EditField_fpmCH1            matlab.ui.control.NumericEditField
        ValuetouseforplottingLabel     matlab.ui.control.Label
        t3_DropDownValue               matlab.ui.control.DropDown
        t3_CheckBox_DisplayExI         matlab.ui.control.CheckBox
        ValueRangeCh1Label             matlab.ui.control.Label
        t3_ValueRange_CH1              matlab.ui.control.EditField
        SegmentsizeEditFieldLabel      matlab.ui.control.Label
        t3_EditField_SegmentSize       matlab.ui.control.NumericEditField
        t3_Save_txt_CheckBox           matlab.ui.control.CheckBox
        t3_DisplayID_Radio             matlab.ui.container.ButtonGroup
        noneButton_3                   matlab.ui.control.RadioButton
        lineageButton                  matlab.ui.control.RadioButton
        manualtrackButton              matlab.ui.control.RadioButton
        t3_Load_cloneFolder            matlab.ui.control.Button
        loadFolderLabel                matlab.ui.control.Label
        t3_Edit_cloneFolder            matlab.ui.control.EditField
        t3_TypeAnalysis_Radio          matlab.ui.container.ButtonGroup
        IntergenerationalButton        matlab.ui.control.RadioButton
        SingleLineageButton            matlab.ui.control.RadioButton
        FounderCellLabel               matlab.ui.control.Label
        t3_FounderCell_DropDown        matlab.ui.control.DropDown
        saveplotteddataastxtfileLabel  matlab.ui.control.Label
        t3_Exp_Title                   matlab.ui.control.EditField
        t3_AutoSave_Radio              matlab.ui.container.ButtonGroup
        NoButton                       matlab.ui.control.RadioButton
        PDFButton                      matlab.ui.control.RadioButton
        TIFFButton                     matlab.ui.control.RadioButton
        SVGButton                      matlab.ui.control.RadioButton
        Label_Asterix2                 matlab.ui.control.Label
        Label_Asterix1                 matlab.ui.control.Label
        t3_Display_Plot                matlab.ui.control.CheckBox
        t3_Summary_Plot                matlab.ui.control.CheckBox
        t3_RadioBox_PlotType           matlab.ui.container.ButtonGroup
        LineageTreeButton              matlab.ui.control.RadioButton
        IndividualClonesButton         matlab.ui.control.RadioButton
        ManualTracksButton             matlab.ui.control.RadioButton
        t3_RadioBox_LineType           matlab.ui.container.ButtonGroup
        singlevalueButton              matlab.ui.control.RadioButton
        bivalueButton                  matlab.ui.control.RadioButton
        singlevalueRatioButton         matlab.ui.control.RadioButton
        segmentationButton             matlab.ui.control.RadioButton
        t3_PlotHystogram               matlab.ui.control.Button
        t3_PlotChannels_Radio          matlab.ui.container.ButtonGroup
        ch_1                           matlab.ui.control.RadioButton
        ch_2                           matlab.ui.control.RadioButton
        both_ch                        matlab.ui.control.RadioButton
        ratio12Button                  matlab.ui.control.RadioButton
        ratio21Button                  matlab.ui.control.RadioButton
        PlotFluorescentChannelsLabel   matlab.ui.control.Label
        LUTColormapLabel               matlab.ui.control.Label
        t3_DropDown_LUT_CH1            matlab.ui.control.DropDown
        Ch1Label                       matlab.ui.control.Label
        Ch2Label                       matlab.ui.control.Label
        ValueRangeCh2Label             matlab.ui.control.Label
        t3_ValueRange_CH2              matlab.ui.control.EditField
        t3_NormalizeColormap           matlab.ui.container.ButtonGroup
        CMap_Lineage                   matlab.ui.control.RadioButton
        CMap_Generation                matlab.ui.control.RadioButton
        Manual_Tracking_Tab            matlab.ui.container.Tab
        START_t5                       matlab.ui.control.Button
        STOP_t5                        matlab.ui.control.Button
        StackLabel                     matlab.ui.control.Label
        t5_Edit_BF_Fold                matlab.ui.control.EditField
        DetmatLabel                    matlab.ui.control.Label
        t5_Edit_Det_File               matlab.ui.control.EditField
        t5_Load_BF                     matlab.ui.control.Button
        t5_Load_Det                    matlab.ui.control.Button
        t5_choose_Outline              matlab.ui.control.CheckBox
        t5_ff_forw_x1                  matlab.ui.control.Button
        t5_ff_back_x1                  matlab.ui.control.Button
        t5_ff_forw_x10                 matlab.ui.control.Button
        t5_ff_back_x10                 matlab.ui.control.Button
        t5_Edit_Frame                  matlab.ui.control.NumericEditField
        t5_Label_totFrames             matlab.ui.control.Label
        t5_Load_Track                  matlab.ui.control.Button
        TracktxtLabel                  matlab.ui.control.Label
        t5_Edit_Track_File             matlab.ui.control.EditField
        t5_B_Delete                    matlab.ui.control.Button
        t5_B_Add                       matlab.ui.control.Button
        t5_B_Delete_All                matlab.ui.control.Button
        SelectTrackLabel               matlab.ui.control.Label
        t5_SelectCellDropDown          matlab.ui.control.DropDown
        t5_B_SaveTrack                 matlab.ui.control.Button
        t5_B_Clear                     matlab.ui.control.Button
        t5_ShowTracks_Radio            matlab.ui.container.ButtonGroup
        noneButton                     matlab.ui.control.RadioButton
        cellpreviousframeButton        matlab.ui.control.RadioButton
        cellonlypresentframeButton_2   matlab.ui.control.RadioButton
        celluptopresentframeButton_2   matlab.ui.control.RadioButton
        cellallframesButton_2          matlab.ui.control.RadioButton
        allcellspreviousframeButton    matlab.ui.control.RadioButton
        allcellsonlypresentframeButton_2  matlab.ui.control.RadioButton
        allcellsuptopresentframeButton_2  matlab.ui.control.RadioButton
        allcellsallframesButton_2      matlab.ui.control.RadioButton
        t5_showAid_CheckBox            matlab.ui.control.CheckBox
        t5_choose_Track                matlab.ui.control.CheckBox
        t5_TrackSize_ButtonGroup       matlab.ui.container.ButtonGroup
        smallButton                    matlab.ui.control.RadioButton
        mediumButton                   matlab.ui.control.RadioButton
        largeButton                    matlab.ui.control.RadioButton
        t5_CellOutline_ButtonGroup     matlab.ui.container.ButtonGroup
        noneButton_2                   matlab.ui.control.RadioButton
        GreenButton                    matlab.ui.control.RadioButton
        AstrackButton                  matlab.ui.control.RadioButton
        t1_Exp_Title_2Label            matlab.ui.control.Label
        t5_TrackFile_Title             matlab.ui.control.EditField
        t5_B_CreateReport              matlab.ui.control.Button
        TrackingReport_Label           matlab.ui.control.Label
        t5_B_Combine_Det2Track         matlab.ui.control.Button
        Combine_Label                  matlab.ui.control.Label
        t5_B_RemoveLast                matlab.ui.control.Button
        t5_TrackingMode_Radio          matlab.ui.container.ButtonGroup
        CellTrackingButton             matlab.ui.control.RadioButton
        PoleMarkingButton              matlab.ui.control.RadioButton
        t5_B_Save_Det                  matlab.ui.control.Button
        t5_showPole_CheckBox           matlab.ui.control.CheckBox
        t5_Color_ButtonGroup           matlab.ui.container.ButtonGroup
        RedButton                      matlab.ui.control.RadioButton
        YellowButton                   matlab.ui.control.RadioButton
        TextOUT                        matlab.ui.control.TextArea
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            GUI_StartUp(app);
        end

        % Button pushed function: PLOT_t3
        function PLOT_t3_ButtonPushed(app, event)
            global APP_opt ;
            APP_PLOT_t3 = 1;
            % Disable Start & Stop in other tabs
            app.START_t1.Enable = 'off';
            app.START_t5.Enable = 'off';
            
            app.TextOUT.Value = sprintf('\n%s\n', 'Plotting ...');
            app.TextOUT.BackgroundColor = [1 1 1] ;       % White bkgr
            app.UIFigure.Color  = [0.75 0.3 0.3] ;        % Red bkgr
            
            Plot_Lng_MAIN_Fnc(app) ;
            
            if APP_opt.ERROR == 0
                app.TextOUT.Value = sprintf('\n%s\n', '... Idle ...' );
                app.TextOUT.BackgroundColor = [0.65 1 0.4] ;       % green bkgr
            elseif APP_opt.ERROR > 0
                app.TextOUT.BackgroundColor = [0.75 0.3 0.3] ;     % red bkgr
                APP_opt.ERROR = 0;
            end
            
            APP_PLOT_t3 = 0;
            % Re-enable the Start & Stop in other tabs
            app.START_t1.Enable = 'on';      
            app.PLOT_t3.Enable  = 'on'; 
            app.START_t5.Enable = 'on';
            
            app.UIFigure.Color = [0.65 0.65 0.65] ;
                        
        end

        % Button pushed function: START_t1
        function START_t1_ButtonPushed(app, event)
            %% START 1 _____________________________________________________________________________________________________________________________
            % --------------------------------------------------------------------------------------------------------------
            global APP_opt ;
            APP_opt.START_t1 = 1;
            APP_opt.BREAK = 0 ;
            % Disable Start & Stop in other tabs
            app.START_t1.Enable = 'off';     
            app.PLOT_t3.Enable  = 'off';
            app.START_t5.Enable = 'off';     
            app.STOP_t5.Enable  = 'off';
            
            app.TextOUT.BackgroundColor = [1 1 1] ;       % White bkgr
            app.UIFigure.Color  = [0.75 0.3 0.3] ;        % Red bkgr
                       
            app.TextOUT.Value = sprintf('\n%s\n', 'Processing ...');            
            
            t1_MAIN_Analysis(app) ;
            
            if APP_opt.ERROR == 0
                if APP_opt.BREAK == 0 
                    app.TextOUT.Value = sprintf('\n%s\n', '... Idle ...' );
                elseif APP_opt.BREAK == 1
                    app.TextOUT.Value = sprintf('%s\n%s', 'Process aborted: Data analysed is lost', '... Idle ...');                 
                end                
                if APP_opt.BREAK ~= 1
                    app.TextOUT.BackgroundColor = [0.65 1 0.4] ;       % green bkgr
                end
            elseif APP_opt.ERROR > 0
                app.TextOUT.BackgroundColor = [0.75 0.3 0.3] ;       % green bkgr
                APP_opt.ERROR = 0;
            end
                         
            APP_opt.START_t1 = 0;
            APP_opt.BREAK = 1;
            % Re-enable the Start & Stop in other tabs
            app.START_t1.Enable = 'on';      
            app.PLOT_t3.Enable  = 'on';  
            app.START_t5.Enable = 'on';      
            app.STOP_t5.Enable  = 'on';
            app.UIFigure.Color = [0.65 0.65 0.65] ;
            
        end

        % Button pushed function: START_t5
        function START_t5_ButtonPushed(app, event)
            %% START 5 _____________________________________________________________________________________________________________________________
            % --------------------------------------------------------------------------------------------------------------
            global APP_opt ;
            
            if isempty(APP_opt.t5_path_BF) && isempty(APP_opt.t5_foldName_BF)
               app.TextOUT.Value = sprintf('\n%s', 'First load a stack of BF images');         
               app.TextOUT.BackgroundColor = [1 1 1] ;        % White bkgr
               app.UIFigure.Color = [0.75 0.3 0.3] ;          % Grey bkgr
               
            else
                APP_opt.START_t5 = 1;
                APP_opt.BREAK = 0 ;
                % Disable Start & Stop in other tabs
                app.START_t1.Enable = 'off';     
                app.PLOT_t3.Enable  = 'off';                
                app.START_t5.Enable = 'off';   
               
                app.TextOUT.BackgroundColor = [1 1 1] ;        % White bkgr
                app.UIFigure.Color  = [0.00 0.45 0.75] ;       % Blue bkgr            
                app.TextOUT.Value = sprintf('\n%s\n', 'Processing ...');
                           
                t5_MAIN_ManualTrack(app) ;
                
                if APP_opt.ERROR == 0
                    if APP_opt.BREAK == 0 
                        app.TextOUT.Value = sprintf('\n%s\n', '... Idle ...' );
                    elseif APP_opt.BREAK == 1
                        app.TextOUT.Value = sprintf('%s\n%s', 'Process aborted: tracked data was not saved', '... Idle ...');                 
                    end                
                    if APP_opt.BREAK ~= 1
                        app.TextOUT.BackgroundColor = [0.65 1 0.4] ;       % green bkgr
                    end
                elseif APP_opt.ERROR > 0
                    app.TextOUT.BackgroundColor = [0.75 0.3 0.3] ;         % red bkgr
                    APP_opt.ERROR = 0;
                end
                             
                APP_opt.START_t5 = 0;
                APP_opt.BREAK = 1;
                % Re-enable the Start & Stop in other tabs
                app.START_t1.Enable = 'on';      
                app.PLOT_t3.Enable  = 'on';
                app.START_t5.Enable = 'on';   
                
                app.UIFigure.Color = [0.65 0.65 0.65] ;         % Grey bkgr
            end
        end

        % Button pushed function: STOP_t1
        function STOP_t1_ButtonPushed(app, event)
            %% STOP 1 _____________________________________________________________________________________________________________________________
            % --------------------------------------------------------------------------------------------------------------
            global APP_opt ;
            APP_opt.BREAK = 1 ;
            APP_opt.ERROR = 0 ;
            app.TextOUT.BackgroundColor = [1 1 1] ;       % White bkgr
            app.UIFigure.Color = [0.65 0.65 0.65] ;
            app.START_t1.Enable = 'on';        
            app.PLOT_t3.Enable  = 'on';            
            app.START_t5.Enable = 'on';       
            app.STOP_t5.Enable  = 'on'; 
        end

        % Button pushed function: STOP_t5
        function STOP_t5_ButtonPushed(app, event)
            %% STOP 5 _____________________________________________________________________________________________________________________________
            % --------------------------------------------------------------------------------------------------------------
            global APP_opt ;
            APP_opt.START_t5 = 0;
            APP_opt.BREAK = 1;
            APP_opt.ERROR = 0 ;
            
            app.TextOUT.BackgroundColor = [1 1 1] ;       % White bkgr
            app.UIFigure.Color = [0.65 0.65 0.65] ;
            app.START_t1.Enable = 'on';       
            app.PLOT_t3.Enable  = 'on';
            app.START_t5.Enable = 'on';
            
            % if it is open, close the tracking figure  (99)
            if ishandle(APP_opt.fig)
                hold off;
                close(APP_opt.fig);
            end
        end

        % Selection changed function: t1_Alg_Radio
        function t1_Alg_Radio_SelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t1_Alg_Radio.SelectedObject;
            if strcmp(selectedRadio.Text , 'Avg Signal')
                APP_opt.choice_AIS = 1 ;        % Avg Intensity Signal  
                APP_opt.choice_M2P = 0 ;        % Memb_2_Pole
                APP_opt.choice_PL = 0 ;         % Polar_Sig                
                % ----- ENABLE or DISABLE the correct parameters for analysis
                app.t1_Edit_M2P_LatMemb.Enable  = 'off';     
                app.t1_Edit_M2P_PoleMemb.Enable = 'off'; 
                app.t1_Edit_M2P_PoleRad.Enable  = 'off';
                
                app.t1_Edit_PL_Memb.Enable      = 'off';            
                app.t1_Edit_PL_PoleMemb.Enable  = 'off';
                app.t1_Edit_PL_PoleRad.Enable   = 'off'; 
                app.t1_Edit_PL_ScaleFact.Enable = 'off';
            
                app.t1_Check_AxisProfile.Enable   = 'on';
                app.t1_Edit_AxisWidth.Enable      = 'on';
                app.t1_Check_Segmentation.Enable  = 'on'; 
                app.t1_Edit_SegmentWidth.Enable   = 'on';
                app.t1_Check_PerimeterSig.Enable  = 'on'; 
                app.t1_Edit_PerimeterWidth.Enable = 'on';               
                
                % Adjust the AIS additional options accordg to their value state
                if app.t1_Check_AxisProfile.Value == 0
                    app.meanvalue_Radio.Enable = 'off';
                    app.maxvalue_Radio.Enable  = 'off';
                elseif app.t1_Check_AxisProfile.Value == 1
                    app.meanvalue_Radio.Enable = 'on';
                    app.maxvalue_Radio.Enable  = 'on';
                end
    
                if app.t1_Check_PerimeterSig.Value == 0
                    app.t1_Edit_PerimeterSpacing.Enable = 'off';
                    app.t1_Label_PerimSpacing.Enable    = 'off';
                elseif app.t1_Check_PerimeterSig.Value == 1
                    app.t1_Edit_PerimeterSpacing.Enable = 'on';
                    app.t1_Label_PerimSpacing.Enable    = 'on';
                end
                
                
            elseif strcmp(selectedRadio.Text , 'Memb_2_Pole')
                APP_opt.choice_AIS = 0 ;        % Avg Intensity Signal
                APP_opt.choice_M2P = 1 ;        % Memb_2_Pole
                APP_opt.choice_PL = 0 ;         % Polar_Sig                
                % ----- ENABLE or DISABLE the correct parameters for analysis
                app.t1_Edit_M2P_LatMemb.Enable  = 'on';     
                app.t1_Edit_M2P_PoleMemb.Enable = 'on'; 
                app.t1_Edit_M2P_PoleRad.Enable  = 'on';
                
                app.t1_Edit_PL_Memb.Enable      = 'off';            
                app.t1_Edit_PL_PoleMemb.Enable  = 'off';
                app.t1_Edit_PL_PoleRad.Enable   = 'off'; 
                app.t1_Edit_PL_ScaleFact.Enable = 'off';
            
                app.t1_Check_AxisProfile.Enable   = 'off';
                app.t1_Edit_AxisWidth.Enable      = 'off';
                app.t1_Check_Segmentation.Enable  = 'off'; 
                app.t1_Edit_SegmentWidth.Enable   = 'off';
                app.t1_Check_PerimeterSig.Enable  = 'off'; 
                app.t1_Edit_PerimeterWidth.Enable = 'off';
                
                % Adjust the AIS additional options accordg to their value state
                app.meanvalue_Radio.Enable = 'off';
                app.maxvalue_Radio.Enable  = 'off';
                app.t1_Edit_PerimeterSpacing.Enable = 'off';
                app.t1_Label_PerimSpacing.Enable    = 'off';
                                
                
            elseif strcmp(selectedRadio.Text , 'Polar_Sig')              
                APP_opt.choice_AIS = 0 ;        % Avg Intensity Signal
                APP_opt.choice_M2P = 0 ;        % Memb_2_Pole
                APP_opt.choice_PL = 1 ;         % Polar_Sig
                % ----- ENABLE or DISABLE the correct parameters for analysis
                app.t1_Edit_M2P_LatMemb.Enable  = 'off';     
                app.t1_Edit_M2P_PoleMemb.Enable = 'off'; 
                app.t1_Edit_M2P_PoleRad.Enable  = 'off';
                
                app.t1_Edit_PL_Memb.Enable      = 'on';            
                app.t1_Edit_PL_PoleMemb.Enable  = 'on';
                app.t1_Edit_PL_PoleRad.Enable   = 'on'; 
                app.t1_Edit_PL_ScaleFact.Enable = 'on';
            
                app.t1_Check_AxisProfile.Enable   = 'off';
                app.t1_Edit_AxisWidth.Enable      = 'off';
                app.t1_Check_Segmentation.Enable  = 'off'; 
                app.t1_Edit_SegmentWidth.Enable   = 'off';
                app.t1_Check_PerimeterSig.Enable  = 'off'; 
                app.t1_Edit_PerimeterWidth.Enable = 'off';
                
                % Adjust the AIS additional options accordg to their value state
                app.meanvalue_Radio.Enable = 'off';
                app.maxvalue_Radio.Enable  = 'off';
                app.t1_Edit_PerimeterSpacing.Enable = 'off';
                app.t1_Label_PerimSpacing.Enable    = 'off';
                
            end             
        end

        % Value changed function: t1_CheckBox_CH2
        function t1_CheckBox_CH2_ValueChanged(app, event)
            global APP_opt ;
            value = app.t1_CheckBox_CH2.Value;
            if value == 0
                act = 'off';   
                APP_opt.t1_choose_Chan_2 = 0;       % do not evaluate channel 2
            elseif value == 1
                act = 'on';
                APP_opt.t1_choose_Chan_2 = 1;       % evaluate channel 2
            end
            app.t1_Load_CH2.Enable = act ;
            app.t1_Edit_CH2_Fold.Enable = act ;
        end

        % Value changed function: t1_Edit_BorderBox
        function t1_Edit_BorderBox_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.BorderBox = app.t1_Edit_BorderBox.Value ;          
        end

        % Value changed function: t1_Edit_M2P_LatMemb
        function t1_Edit_M2P_LatMemb_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.M2P_LatMemb  = app.t1_Edit_M2P_LatMemb.Value ; 
        end

        % Value changed function: t1_Edit_PL_PoleRad
        function t1_Edit_PL_PoleRad_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.PL_PoleRad  = app.t1_Edit_PL_PoleRad.Value  ;          
        end

        % Value changed function: t1_Edit_PL_PoleMemb
        function t1_Edit_PL_PoleMemb_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.PL_PoleMemb = app.t1_Edit_PL_PoleMemb.Value ;
        end

        % Value changed function: t1_Edit_PL_Memb
        function t1_Edit_PL_Memb_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.PL_AllMemb  = app.t1_Edit_PL_Memb.Value ;
        end

        % Value changed function: t1_Edit_WaitPlot
        function t1_Edit_WaitPlot_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.plot_pause = app.t1_Edit_WaitPlot.Value;
        end

        % Value changed function: t1_Exp_Title
        function t1_Exp_Title_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.t1_exp_name = app.t1_Exp_Title.Value;            
        end

        % Button pushed function: t1_Load_BF
        function t1_Load_BF_ButtonPushed(app, event)
            GUI_t1_Load_BrightField(app);
        end

        % Button pushed function: t1_Load_CH1
        function t1_Load_CH1_ButtonPushed(app, event)
            GUI_t1_Load_Channel_1(app);
        end

        % Button pushed function: t1_Load_CH2
        function t1_Load_CH2_ButtonPushed(app, event)
            GUI_t1_Load_Channel_2(app);
        end

        % Callback function
        function t1_Load_Det_ButtonPushed(app, event)
            GUI_t1_Load_DetFile(app);
        end

        % Value changed function: t1_ShowPlot
        function t1_ShowPlot_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.choice_plot = app.t1_ShowPlot.Value ;
        end

        % Value changed function: t3_CheckBox_DisplayExI
        function t3_CheckBox_DisplayExI_ValueChanged(app, event)
            global APP_opt ;
            value = app.t3_CheckBox_DisplayExI.Value;
            if value == 0
                act = 'off';
                APP_opt.t3_display_ExI = 0 ;
            elseif value == 1
                act = 'on';
                APP_opt.t3_display_ExI = 1 ;
            end
        end

        % Value changed function: t3_CheckBox_DisplayPoles
        function t3_CheckBox_DisplayPoles_ValueChanged(app, event)
            global APP_opt ;
            value = app.t3_CheckBox_DisplayPoles.Value;
            if value == 0
                act = 'off';
                APP_opt.t3_display_PL = 0 ;
            elseif value == 1
                act = 'on';
                APP_opt.t3_display_PL = 1 ;
            end
        end

        % Value changed function: t3_DropDownValue
        function t3_DropDownValue_ValueChanged(app, event)
            global APP_opt ;
            value = app.t3_DropDownValue.Value;
            Str_value = strsplit(value, '-');
            APP_opt.t3_PlotOpt_C = str2num(Str_value{1}(1));
        end

        % Value changed function: t3_DropDown_LUT_CH2
        function t3_DropDown_LUT_CH2_ValueChanged(app, event)
            global APP_opt ;            
            APP_opt.t3_ColorMap_LUT_CH2 = (app.t3_DropDown_LUT_CH2.Value);
        end

        % Value changed function: t3_EditField_Borderline
        function t3_EditField_Borderline_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.t3_BorderLineWidth  = app.t3_EditField_Borderline.Value;            
        end

        % Value changed function: t3_EditField_LineSize
        function t3_EditField_LineSize_ValueChanged(app, event)
            global APP_opt ;  
            APP_opt.t3_height_PlotLine = app.t3_EditField_LineSize.Value;           
        end

        % Value changed function: t3_EditField_LinesDistance
        function t3_EditField_LinesDistance_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.t3_Dist_btw_PlotLines = app.t3_EditField_LinesDistance.Value;
        end

        % Value changed function: t3_EditField_SegmentSize
        function t3_EditField_SegmentSize_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.t3_B4_LenStep = app.t3_EditField_SegmentSize.Value;
        end

        % Value changed function: t3_EditField_fpmCH1
        function t3_EditField_fpmCH1_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.t3_fpm_CH1 = app.t3_EditField_fpmCH1.Value;
        end

        % Button pushed function: t3_Load_cloneList
        function t3_Load_cloneList_ButtonPushed(app, event)
            global APP_opt 
            
            [FileName, PathName] = uigetfile({'*.mat'},'Select clone_List.mat file');
            if FileName == 0 
                   FileName = [] ;
                   PathName = [] ;
                   app.TextOUT.Value = sprintf('\n%s\n%s',  'No clone_List.mat file provided !!!');
                   return
            end            
            APP_opt.t3_path_cloneList = PathName ;
            APP_opt.t3_file_cloneList = FileName ;
            app.t3_Edit_cloneList.Value = [APP_opt.t3_path_cloneList , APP_opt.t3_file_cloneList]; 
           
            % Check the file provided is a cloneList and which algorithm was used...            
            Warning = Check_CloneList_File(app) ;
            Warning_opt = Find_FounderCell(app) ;           
            Warning = Warning + Warning_opt ;
            Dis_Enable_PlotOptions(app, Warning, 1 )
            if Warning >= 1
                return
            else                
                % ... and update the options in menus accordingly.            
                Update_PlotOptions('All_opt', app) ;
                app.TextOUT.Value = sprintf('\n%s\n', '... Idle ...' );
                app.TextOUT.BackgroundColor = [1 1 1] ;       % green bkgr
            end           
            
        end

        % Value changed function: t3_ValueRange_CH1
        function t3_ValueRange_CH1_ValueChanged(app, event)
            global APP_opt ;
            temp_Value = app.t3_ValueRange_CH1.Value;
            Value_Range = strsplit(temp_Value, ':')
            if length(Value_Range) ~= 2
                app.TextOUT.Value = sprintf('\n%s\n%s',  ['!!! Format Value Range is not correct !!!'],...
                                    ['Format must be in for  XX:YY. Place min:max for automatic detection of range']);
                app.TextOUT.BackgroundColor = [0.75 0.3 0.3] ;       % red bkgr
                APP_opt.ERROR = 1; 
                return
            end
            [nstr, status] = str2num(Value_Range{1}) ;    % if status == 0, conversion was not successful  
            if status == 0  &&  ~strcmpi(nstr, 'MIN')
                app.TextOUT.Value = sprintf('\n%s\n%s',  ['!!! Format Value Range is not correct !!!'],...
                                    ['Format must be in for  XX:YY. Place min:max for automatic detection of range']);
                app.TextOUT.BackgroundColor = [0.75 0.3 0.3] ;       % red bkgr
                APP_opt.ERROR = 1; 
                return
            end
            [nstr, status] = str2num(Value_Range{2}) ;    % if status == 0, conversion was not successful  
            if status == 0  &&  ~strcmpi(nstr, 'MAX')
                app.TextOUT.Value = sprintf('\n%s\n%s',  ['!!! Format Value Range is not correct !!!'],...
                                    ['Format must be in for  XX:YY. Place min:max for automatic detection of range']);
                app.TextOUT.BackgroundColor = [0.75 0.3 0.3] ;       % red bkgr
                APP_opt.ERROR = 1; 
                return
            end
            APP_opt.t3_Value_Range = temp_Value ;
        end

        % Button pushed function: t5_B_Add
        function t5_B_Add_ButtonPushed(app, event)
            BFun_Add_Cell(app) ;
        end

        % Button pushed function: t5_B_Clear
        function t5_B_Clear_ButtonPushed(app, event)
            BFun_Clear_Cell(app) ;
        end

        % Button pushed function: t5_B_Delete
        function t5_B_DeleteButtonPushed(app, event)
            BFun_Delete(app) ;
        end

        % Button pushed function: t5_B_Delete_All
        function t5_B_Delete_All_ButtonPushed(app, event)
            BFun_Delete_All(app) ;
        end

        % Button pushed function: t5_B_SaveTrack
        function t5_B_SaveTrack_ButtonPushed(app, event)
            BFun_SaveTracks(app) ;
        end

        % Selection changed function: t5_CellOutline_ButtonGroup
        function t5_CellOutline_ButtonGroupSelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t5_CellOutline_ButtonGroup.SelectedObject;
            
            if  strcmp( selectedRadio.Text ,   'none')
                APP_opt.t5_display_Outline = 0 ;     % cell outline style  
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;         % REFRESH the frame and update displayed options
                end
                
            elseif strcmp(selectedRadio.Text , 'Green')
                APP_opt.t5_display_Outline = 1 ;     % cell outline style   
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end
                
            elseif strcmp(selectedRadio.Text , 'As track')
                APP_opt.t5_display_Outline = 2 ;     % cell outline style  
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end
            end
        end

        % Selection changed function: t5_Color_ButtonGroup
        function t5_Color_ButtonGroupSelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t5_Color_ButtonGroup.SelectedObject;
           
            if  strcmp( selectedRadio.Text ,   'Red')
                APP_opt.t5_display_Color = [.9 .2 0];    % Display track with color RGB      
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;         % REFRESH the frame and update displayed options
                end
                
            elseif strcmp(selectedRadio.Text , 'Yellow')
                APP_opt.t5_display_Color = [1 .9 0];    % Display track with color RGB                 
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end
            end
            
        end

        % Value changed function: t5_Edit_Frame
        function t5_Edit_Frame_ValueChanged(app, event)
            global APP_opt ;
            value = app.t5_Edit_Frame.Value ; 
            
            if value < 1 || value > size(APP_opt.t5_srcFiles_BF,1)
                app.TextOUT.Value = sprintf('\n%s\n%s%d',  ['!!! Frame number is outsize stack range !!!'],...
                                           ['Frames go from: 1 to '], size(APP_opt.t5_srcFiles_BF,1));
                app.TextOUT.BackgroundColor = [0.75 0.3 0.3] ;
                
            else
                delta = value - APP_opt.t5_ff;    
                if delta > 0
                    fr_forward( app, delta );    % go forward of n-frames = delta
                elseif delta < 0
                    fr_backward( app, -delta );       % go back of n-frames = delta
                end                
                app.TextOUT.Value = sprintf('\n%s',  ['... Idle ...']);
                app.TextOUT.BackgroundColor = [1 1 1] ;         % White bkgr
            end
            
        end

        % Button pushed function: t5_Load_BF
        function t5_Load_BF_ButtonPushed(app, event)
            GUI_t5_Load_BrightField(app)
        end

        % Button pushed function: t5_Load_Det
        function t5_Load_Det_ButtonPushed(app, event)
            global APP_opt ;
            global oDet ;
                        
            APP_opt.t5_path_Det = '' ;
            APP_opt.t5_fileName_Det = '' ;
            app.t5_Edit_Det_File.Value = '';
            
            [FileName, PathName] = uigetfile({'*.mat'},'Select Oufti Detection.mat file');
            if FileName == 0 
                FileName = [] ;
                PathName = [] ;
                app.TextOUT.Value = sprintf('\n%s\n%s',  'No detection.mat file provided !!!');
                % Disable saving button
                app.t5_B_Save_Det.Enable = 'off' ;
                % Disable the Pole Marking mode option
                if strcmp(app.PoleMarkingButton.Enable , 'on')
                    app.PoleMarkingButton.Enable = 'off' ;
                    app.PoleMarkingButton.Value = 0 ;
                    APP_opt.t5_Mode_CellTrack = 1 ;
                end
                return
            end               
            
            APP_opt.t5_path_Det = PathName ;
            APP_opt.t5_fileName_Det = FileName ;
            app.t5_Edit_Det_File.Value = [APP_opt.t5_path_Det , APP_opt.t5_fileName_Det];                 
           
            % Load all the content of Detection file in oDet variable
            app.TextOUT.Value = sprintf('\n%s',  'Loading Det.mat file' );
            oDet = load([APP_opt.t5_path_Det , APP_opt.t5_fileName_Det ]);
            app.TextOUT.Value = sprintf('\n%s',  'Processing ...' );  
            
            % If Detection file is provided, activate Pole Marking mode and Save button           
            if strcmp(app.PoleMarkingButton.Enable , 'off')
                app.PoleMarkingButton.Enable = 'on' ;
                app.t5_B_Save_Det.Enable = 'on' ;
            end
            
        end

        % Button pushed function: t5_Load_Track
        function t5_Load_Track_ButtonPushed(app, event)
            global APP_opt ;
            [FileName, PathName] = uigetfile({'*.txt'},'Select Manual_Track.txt file');
            if FileName == 0
                   FileName = [] ;
                   PathName = [] ;
                   app.TextOUT.Value = sprintf('\n%s\n%s',  'No Manual_Track.txt file provided !!!');
                   return
            end  
            APP_opt.t5_path_Track = PathName ;
            APP_opt.t5_fileName_Track = FileName ;
            app.t5_Edit_Track_File.Value = [APP_opt.t5_path_Track , APP_opt.t5_fileName_Track];                 
            
        end

        % Value changed function: t5_SelectCellDropDown
        function t5_SelectCellDropDown_ValueChanged(app, event)
            BFun_Sele_Cell(app) ;
        end

        % Selection changed function: t5_ShowTracks_Radio
        function t5_ShowTracks_Radio_SelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t5_ShowTracks_Radio.SelectedObject;
            
            if  strcmp( selectedRadio.Text ,   'none')
                APP_opt.t5_display_Track = 0 ;
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;         % REFRESH the frame and update displayed options
                end
                
            elseif strcmp(selectedRadio.Text , '# cell: previous frame')
                APP_opt.t5_display_Track = 1 ;
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end
                
            elseif strcmp(selectedRadio.Text , '# cell: only present frame')
                APP_opt.t5_display_Track = 2 ;
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end
                
            elseif strcmp(selectedRadio.Text , '# cell: up to present frame')              
                APP_opt.t5_display_Track = 3 ;
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end
            
            elseif strcmp(selectedRadio.Text , '# cell: all frames')              
                APP_opt.t5_display_Track = 4 ;
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end  
                
           elseif strcmp(selectedRadio.Text , 'all cells: previous frame')              
                APP_opt.t5_display_Track = 5 ;
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end 
                
            elseif strcmp(selectedRadio.Text , 'all cells: only present frame')              
                APP_opt.t5_display_Track = 6 ;
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end 
                
            elseif strcmp(selectedRadio.Text , 'all cells: up to present frame')              
                APP_opt.t5_display_Track = 7 ;
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end 
                
            elseif strcmp(selectedRadio.Text , 'all cells: all frames')              
                APP_opt.t5_display_Track = 8 ;
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end 
            end
            
        end

        % Selection changed function: t5_TrackSize_ButtonGroup
        function t5_TrackSize_ButtonGroupSelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t5_TrackSize_ButtonGroup.SelectedObject;
            
            if  strcmp( selectedRadio.Text ,   'small')
                APP_opt.t5_display_Marker = 10 ;     % marker size  
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;         % REFRESH the frame and update displayed options
                end
                
            elseif strcmp(selectedRadio.Text , 'medium')
                APP_opt.t5_display_Marker = 14 ;     % marker size  
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end
                
            elseif strcmp(selectedRadio.Text , 'large')
                APP_opt.t5_display_Marker = 18 ;     % marker size  
                if APP_opt.START_t5 == 1       % only if we are actively tracking
                    ReFresh_Frame;
                end
            end
        end

        % Value changed function: t5_choose_Outline
        function t5_choose_Outline_ValueChanged(app, event)
            global APP_opt ;
            value = app.t5_choose_Outline.Value;
            if value == 0
                act = 'off'; 
                APP_opt.t5_display_Det = 0;    % not added
            elseif value == 1
                act = 'on';
                APP_opt.t5_display_Det = 1;    % use it
            end
            app.t5_Edit_Det_File.Enable = act ;
            app.t5_Load_Det.Enable = act ;
        end

        % Value changed function: t5_choose_Track
        function t5_choose_Track_ValueChanged(app, event)
            global APP_opt ;
            value =  app.t5_choose_Track.Value;
            if value == 0
                act = 'off'; 
                APP_opt.t5_display_Track = 0;    % not added
            elseif value == 1
                act = 'on';
                APP_opt.t5_display_Track = 1;    % use it
            end
            app.t5_Edit_Track_File.Enable = act ;
            app.t5_Load_Track.Enable = act ; 
        end

        % Button pushed function: t5_ff_back_x1
        function t5_ff_back_x1_ButtonPushed(app, event)
            global APP_opt ;
            fr_backward(app, 1) ;
        end

        % Button pushed function: t5_ff_back_x10
        function t5_ff_back_x10_ButtonPushed(app, event)
            global APP_opt ;
            fr_backward(app, 10) ;
        end

        % Button pushed function: t5_ff_forw_x1
        function t5_ff_forw_x1_ButtonPushed(app, event)
            global APP_opt ;
            fr_forward(app, 1) ;        
        end

        % Button pushed function: t5_ff_forw_x10
        function t5_ff_forw_x10ButtonPushed(app, event)
            global APP_opt ;
            fr_forward(app, 10) ;
        end

        % Value changed function: t5_showAid_CheckBox
        function t5_showAid_CheckBox_ValueChanged(app, event)
            global APP_opt ;
            value = app.t5_showAid_CheckBox.Value;
            if value == 0
                APP_opt.t5_display_TrackAid = 0;    % Display frame border Red
            elseif value == 1
                APP_opt.t5_display_TrackAid = 1;    % Display frame border Blue
            end
            
            if APP_opt.START_t5 == 1       % only if we are actively tracking
                % REFRESH the frame and update displayed options
                ReFresh_Frame;
            end   
        end

        % Value changed function: t5_TrackFile_Title
        function t5_TrackFile_Title_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.t5_exp_name = app.t5_TrackFile_Title.Value; 
        end

        % Button pushed function: t5_B_CreateReport
        function t5_B_CreateReport_ButtonPushed(app, event)
            global APP_opt ;            
            if app.t5_choose_Track.Value == 1   &&  ~isempty( APP_opt.t5_fileName_Track )
               if app.t5_choose_Outline.Value == 1 
                   app.TextOUT.Value = sprintf('\n%s', [ 'Combining Detection and Track files... ']);
                   Report_Track2Det ;
                   app.TextOUT.Value = sprintf('\n%s', [ 'Idle ... ']);
               else
                   app.TextOUT.Value = sprintf('\n%s\n',  ['!!! No Det.mat file provided !!!'] );
               end                   
            else
                app.TextOUT.Value = sprintf('\n%s\n',  ['!!! No Track.txt file provided !!!'] );
            end 
        end

        % Button pushed function: t5_B_Combine_Det2Track
        function t5_B_Combine_Det2Track_ButtonPushed(app, event)
            global APP_opt ;            
            if app.t5_choose_Track.Value == 1   &&  ~isempty( APP_opt.t5_fileName_Track )
               if app.t5_choose_Outline.Value == 1 
                   app.TextOUT.Value = sprintf('\n%s', [ 'Combining Detection and Track files... ']);
                   Combine_Det2Track;
                   app.TextOUT.Value = sprintf('\n%s', [ 'Idle ... ']);
               else
                   app.TextOUT.Value = sprintf('\n%s\n',  ['!!! No Det.mat file provided !!!'] );
               end                   
            else
                app.TextOUT.Value = sprintf('\n%s\n',  ['!!! No Track.txt file provided !!!'] );
            end   
        end

        % Value changed function: t3_Exp_Title
        function t3_Exp_Title_ValueChanged(app, event)
            global APP_opt;
            APP_opt.t3_exp_name = app.t3_Exp_Title.Value ;         
        end

        % Value changed function: t3_Save_txt_CheckBox
        function t3_Save_txt_CheckBox_ValueChanged(app, event)
            global APP_opt;     
            value = app.t3_Save_txt_CheckBox.Value;  
            if value == 0
                act = 'off';
                APP_opt.t3_choose_Save_txt = 0;     % do not save the plotted data
            elseif value == 1
                act = 'on';
                APP_opt.t3_choose_Save_txt = 1;     % do not save the plotted data
            end
            app.t3_Exp_Title.Enable = act ;           
        end

        % Button pushed function: t5_B_RemoveLast
        function t5_B_RemoveLast_ButtonPushed(app, event)
            BFun_Remove_Point(app) ;
        end

        % Value changed function: t3_FounderCell_DropDown
        function t3_FounderCell_DropDown_ValueChanged(app, event)
            global APP_opt;
            APP_opt.t3_PlotOpt_NLineage = app.t3_FounderCell_DropDown.Value;            
        end

        % Selection changed function: t3_DisplayID_Radio
        function t3_DisplayID_Radio_SelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t3_DisplayID_Radio.SelectedObject;
            
            if  strcmp( selectedRadio.Text ,   'none')
                APP_opt.t3_display_cID = 0 ;
            
            elseif strcmp(selectedRadio.Text , 'lineage')
                APP_opt.t3_display_cID = 1 ;
                
            elseif strcmp(selectedRadio.Text , 'manual track')
                APP_opt.t3_display_cID = 2 ;            
            end
        end

        % Button pushed function: t3_Load_cloneFolder
        function t3_Load_cloneFolder_ButtonPushed(app, event)
            global APP_opt            
            FullPath = uigetdir; 
            if FullPath == 0
                   FullPath = [];
                   app.TextOUT.Value = sprintf('\n%s\n%s',  'No path provided!!!');
                   return
            end            
            [PathName, FoldName]  = fileparts(FullPath) ;    
            APP_opt.t3_path_cloneFolder = PathName ;
            APP_opt.t3_fold_cloneFolder = FoldName ;
            app.t3_Edit_cloneFolder.Value = [APP_opt.t3_path_cloneFolder filesep  APP_opt.t3_fold_cloneFolder]; 
            
            app.TextOUT.BackgroundColor = [1 1 1]  ;
            
            % First check that folder contains files in appropriate format and save a list of the selected ones
            Warning = Check_CloneList_Folder(app) ;
            Dis_Enable_PlotOptions(app, Warning, 2 )
            if Warning >= 1
                return
            else                
                % ... and update the options in menus accordingly.            
                Update_PlotOptions('All_opt', app) ;
                app.TextOUT.Value = sprintf('\n%s\n', '... Idle ...' );
                app.TextOUT.BackgroundColor = [1 1 1] ;       % green bkgr
            end   
            
        end

        % Selection changed function: t3_TypeAnalysis_Radio
        function t3_TypeAnalysis_Radio_SelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t3_TypeAnalysis_Radio.SelectedObject;
            
            if  strcmp( selectedRadio.Text ,   'Intergenerational')
                Warning = Check_CloneList_Folder(app) ;
                Dis_Enable_PlotOptions(app, Warning, 2 );
                
                APP_opt.t3_Generation_VS_Single = 0 ;
                
                app.t3_Edit_cloneList.Enable = 'off';         app.t3_Load_cloneList.Enable = 'off';
                app.t3_Load_cloneFolder.Enable = 'on';        app.t3_Edit_cloneFolder.Enable = 'on';
                app.t3_Summary_Plot.Enable = 'on';
                app.CMap_Lineage.Enable = 'on';
                app.CMap_Generation.Enable = 'on';
            
                
            elseif strcmp(selectedRadio.Text , 'Single Lineage')                
                Warning = Check_CloneList_File(app) ;
                Warning_opt = Find_FounderCell(app) ;           
                Warning = Warning + Warning_opt ;
                Dis_Enable_PlotOptions(app, Warning, 1 );
                
                APP_opt.t3_Generation_VS_Single = 1 ;   
                
                app.t3_Edit_cloneList.Enable = 'on';          app.t3_Load_cloneList.Enable = 'on';
                app.t3_Load_cloneFolder.Enable = 'off';       app.t3_Edit_cloneFolder.Enable = 'off';
                app.t3_Summary_Plot.Enable = 'off';
                app.CMap_Lineage.Enable = 'off';
                app.CMap_Generation.Enable = 'off';
                
            end
            
        end

        % Selection changed function: t3_AutoSave_Radio
        function t3_AutoSave_Radio_SelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t3_AutoSave_Radio.SelectedObject;
            if  strcmp( selectedRadio.Text ,   'No')
                APP_opt.t3_choose_AutoSave_Plot = 0 ;
                
            elseif strcmp(selectedRadio.Text , '.PDF')
                APP_opt.t3_choose_AutoSave_Plot = 1 ;   
                
            elseif strcmp(selectedRadio.Text , '.TIFF')
                APP_opt.t3_choose_AutoSave_Plot = 2 ; 
            
            elseif strcmp(selectedRadio.Text , '.SVG')
                APP_opt.t3_choose_AutoSave_Plot = 3 ;
                
            end
        end

        % Value changed function: t3_Display_Plot
        function t3_Display_Plot_ValueChanged(app, event)
            global APP_opt;  
            value = app.t3_Display_Plot.Value;  
            if value == 0
                APP_opt.t3_choose_Display_Plot = 0;     % do not save the plotted data
            elseif value == 1
                APP_opt.t3_choose_Display_Plot = 1;     % do not save the plotted data
            end
        end

        % Value changed function: t3_Summary_Plot
        function t3_Summary_Plot_ValueChanged(app, event)
            global APP_opt;  
            value = app.t3_Summary_Plot.Value;  
            if value == 0
                APP_opt.t3_choose_Summary_Plot = 0;     % do not save the plotted data
            elseif value == 1
                APP_opt.t3_choose_Summary_Plot = 1;     % do not save the plotted data  
            end
        end

        % Button pushed function: t1_Create_cloneList
        function t1_Create_cloneList_ButtonPushed(app, event)
            global APP_opt ;
            % Disable Start & Stop in other tabs
            APP_opt.BREAK = 0 ;
            app.START_t1.Enable = 'off';     
            app.PLOT_t3.Enable  = 'off';
            app.START_t5.Enable = 'off';     
            app.STOP_t5.Enable  = 'off';
            
            app.TextOUT.BackgroundColor = [1 1 1] ;       % White bkgr
            app.UIFigure.Color  = [0.75 0.3 0.3] ;        % Red bkgr                       
            app.TextOUT.Value = sprintf('\n%s\n', 'Processing ...');
            
            Lng_Create_clone_List(app) ;
            
            if APP_opt.ERROR == 0
                app.TextOUT.Value = sprintf('\n%s\n', '... Idle ...' );
                app.TextOUT.BackgroundColor = [0.65 1 0.4] ;       % green bkgr
    
            elseif APP_opt.ERROR > 0
                app.TextOUT.BackgroundColor = [0.75 0.3 0.3] ;       % Red bkgr
                APP_opt.ERROR = 0;
            end
                         
            APP_opt.START_t1 = 0;
            APP_opt.BREAK = 1;
            % Re-enable the Start & Stop in other tabs
            app.START_t1.Enable = 'on';      
            app.PLOT_t3.Enable  = 'on';  
            app.START_t5.Enable = 'on';      
            app.STOP_t5.Enable  = 'on';
            app.UIFigure.Color = [0.65 0.65 0.65] ;
        end

        % Button pushed function: t1_Load_Res_D2T
        function t1_Load_Res_D2T_ButtonPushed(app, event)
            global APP_opt ;
            
            [FileName, PathName] = uigetfile({'*.mat'},'Select Res_D2T.mat file'); 
            if FileName == 0 
                   FileName = [] ;
                   PathName = [] ;
                   app.TextOUT.Value = sprintf('\n%s\n%s',  'No detection.mat file provided !!!');
                   return
            end                        
            APP_opt.t1_path_Res_D2T = PathName ;
            APP_opt.t1_file_Res_D2T = FileName ;
            app.t1_Edit_Res_D2T.Value = [APP_opt.t1_path_Res_D2T , APP_opt.t1_file_Res_D2T];
        end

        % Value changed function: t1_CheckBox_CreateCloneList
        function t1_CheckBox_CreateCloneList_ValueChanged(app, event)
            global APP_opt ;
            value = app.t1_CheckBox_CreateCloneList.Value;
            if value == 0
                APP_opt.t1_choose_CreateCloneList = 0;
                act = 'off'; 
            elseif value == 1
                APP_opt.t1_choose_CreateCloneList = 1;
                act = 'on';
            end            
            app.t1_Load_Res_D2T.Enable = act;
            app.t1_Edit_Res_D2T.Enable = act;
            app.t1_Create_cloneList.Enable = act;
             
        end

        % Value changed function: t1_Check_AxisProfile
        function t1_Check_AxisProfile_ValueChanged(app, event)
            global APP_opt ;
            value = app.t1_Check_AxisProfile.Value;
            if value == 0
                act = 'off';
                APP_opt.AIS_EvalAxisProfile = 0 ;
            elseif value == 1
                act = 'on';
                APP_opt.AIS_EvalAxisProfile = 1 ;
            end
            app.meanvalue_Radio.Enable = act ;
            app.maxvalue_Radio.Enable = act ;
        end

        % Value changed function: t1_Check_CH3_Marker
        function t1_Check_CH3_Marker_ValueChanged(app, event)
            global APP_opt ;
            value = app.t1_Check_CH3_Marker.Value;
            if value == 0
                APP_opt.t1_CH3_Marker = 0 ;
            elseif value == 1
                APP_opt.t1_CH3_Marker = 1 ;
            end          
        end

        % Selection changed function: t3_RadioBox_LineType
        function t3_RadioBox_LineType_SelectionChanged(app, event)
            Update_PlotOptions('B_opt', app) ;
        end

        % Selection changed function: t3_RadioBox_PlotType
        function t3_RadioBox_PlotType_SelectionChanged(app, event)
            Update_PlotOptions('A_opt', app) ;
        end

        % Button pushed function: t3_PlotHystogram
        function t3_PlotHystogram_ButtonPushed(app, event)
            global APP_opt ; 
            if  APP_opt.t3_Generation_VS_Single == 0    % Intergenerational
                % Load and reorganize all clone_list in the folder given by the user into
                % a gen_List, where each element stores all the clones of x-th generation
                [gen_List, gen_Fold] = Create_GenList ;
                [~,~] = Hist_Values_generationList(1, gen_List) ;
                                    
            elseif  APP_opt.t3_Generation_VS_Single == 1    % Single Lineage          
                [~,~] = Hist_Values_cloneList(1) ;
            end
        end

        % Value changed function: t1_CheckBox_CH3
        function t1_CheckBox_CH3_ValueChanged(app, event)
            global APP_opt ;
            value = app.t1_CheckBox_CH3.Value;
            if value == 0
                act = 'off';   
                APP_opt.t1_choose_Chan_3 = 0;       % do not evaluate channel 3
            elseif value == 1
                act = 'on';
                APP_opt.t1_choose_Chan_3 = 1;       % evaluate channel 3
            end
            app.t1_Load_CH3.Enable = act ;
            app.t1_Edit_CH3_Fold.Enable = act ;
            app.t1_Check_CH3_Marker.Enable = act ;
        end

        % Button pushed function: t1_Load_CH3
        function t1_Load_CH3_ButtonPushed(app, event)
            GUI_t1_Load_Channel_3(app);           
        end

        % Value changed function: t1_CheckBox_BF
        function t1_CheckBox_BF_ValueChanged(app, event)
            global APP_opt ;
            value = app.t1_CheckBox_BF.Value;
            if value == 0
                act = 'off';   
                APP_opt.t1_choose_BrightField = 0;       % do not evaluate Bright Field
            elseif value == 1
                act = 'on';
                APP_opt.t1_choose_BrightField = 1;       % Take Bright Field and store a cropped image for each cell
            end
            app.t1_Load_BF.Enable = act ;
            app.t1_Edit_BF_Fold.Enable = act ;
        end

        % Value changed function: t1_Edit_M2P_PoleMemb
        function t1_Edit_M2P_PoleMemb_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.M2P_PoleMemb = app.t1_Edit_M2P_PoleMemb.Value;
        end

        % Value changed function: t1_Edit_M2P_PoleRad
        function t1_Edit_M2P_PoleRad_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.M2P_PoleRad  = app.t1_Edit_M2P_PoleRad.Value ;
        end

        % Value changed function: t1_Edit_PL_ScaleFact
        function t1_Edit_PL_ScaleFact_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.PL_ScaleFact = app.t1_Edit_PL_ScaleFact.Value;
        end

        % Value changed function: t1_Edit_AxisWidth
        function t1_Edit_AxisWidth_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.AIS_AxisWidth = app.t1_Edit_AxisWidth.Value;
        end

        % Selection changed function: t1_ProfileRadioGroup
        function t1_ProfileRadioGroup_SelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t1_ProfileRadioGroup.SelectedObject;
            if strcmp(selectedRadio.Text ,     'mean value')
                APP_opt.AIS_ProfileType = 1 ; 
            elseif strcmp(selectedRadio.Text , 'max value')
                APP_opt.AIS_ProfileType = 2 ;
            end
        end

        % Value changed function: t1_Check_Segmentation
        function t1_Check_Segmentation_ValueChanged(app, event)
            global APP_opt ;
            value = app.t1_Check_Segmentation.Value;
            if value == 0
                APP_opt.AIS_EvalSegmentation = 0 ;
            elseif value == 1
                APP_opt.AIS_EvalSegmentation = 1 ;
            end
        end

        % Value changed function: t1_Edit_SegmentWidth
        function t1_Edit_SegmentWidth_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.AIS_SegmentLength = app.t1_Edit_SegmentWidth.Value;
        end

        % Callback function
        function t1_Edit_PerimeterWidth_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.AIS_PerimWidth = app.t1_Edit_PerimeterWidth.Value;            
        end

        % Callback function
        function t1_Check_PerimeterSig_ValueChanged(app, event)
            global APP_opt ;
            value = app.t1_Check_PerimeterSig.Value;
            if value == 0
                APP_opt.AIS_EvalPerim = 0 ;
                app.t1_Edit_PerimeterSpacing.Enable = 'off';  
                app.t1_Label_PerimSpacing.Enable  = 'off';          
            elseif value == 1
                APP_opt.AIS_EvalPerim = 1 ;
                app.t1_Edit_PerimeterSpacing.Enable = 'on';
                app.t1_Label_PerimSpacing.Enable  = 'on';
            end
        end

        % Callback function
        function t1_Edit_PerimeterSpacing_ValueChanged(app, event)
            global APP_opt ;
            APP_opt.AIS_PerimSpacing = app.t1_Edit_PerimeterSpacing.Value;            
        end

        % Selection changed function: t3_PlotChannels_Radio
        function t3_PlotChannels_Radio_SelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t3_PlotChannels_Radio.SelectedObject;
            if  strcmp( selectedRadio.Text ,   '1')
                APP_opt.t3_choose_ChannelMode = 1 ;
                
            elseif strcmp(selectedRadio.Text , '2')
                APP_opt.t3_choose_ChannelMode = 2 ;   
                
            elseif strcmp(selectedRadio.Text , 'both 1 and 2')
                APP_opt.t3_choose_ChannelMode = 3 ; 
                
            elseif strcmp(selectedRadio.Text , 'ratio 1/2')
                APP_opt.t3_choose_ChannelMode = 4 ;            
            
            elseif strcmp(selectedRadio.Text , 'ratio 2/1')
                APP_opt.t3_choose_ChannelMode = 5 ; 
                
            end
        end

        % Value changed function: t3_DropDown_LUT_CH1
        function t3_DropDown_LUT_CH1_ValueChanged(app, event)
            global APP_opt ;            
            APP_opt.t3_ColorMap_LUT_CH1 = (app.t3_DropDown_LUT_CH1.Value);
        end

        % Selection changed function: t3_NormalizeColormap
        function t3_NormalizeColormap_SelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t3_NormalizeColormap.SelectedObject;
            if  strcmp( selectedRadio.Text ,   'Lineage')
                APP_opt.t3_choose_NormColormap = 1 ;
                
            elseif strcmp(selectedRadio.Text , 'Generation')
                APP_opt.t3_choose_NormColormap = 2 ;   
            end
        end

        % Selection changed function: t5_TrackingMode_Radio
        function t5_TrackingMode_Radio_SelectionChanged(app, event)
            global APP_opt ;
            selectedRadio = app.t5_TrackingMode_Radio.SelectedObject;
           
            if  strcmp( selectedRadio.Text ,   'Cell Tracking')
                APP_opt.t5_Mode_CellTrack = 1 ;     
                if APP_opt.START_t5 == 1           % only if we are actively tracking
                    ReFresh_Frame;                 % REFRESH the frame and update displayed options
                end
                
            elseif strcmp(selectedRadio.Text , 'Pole Marking')
                APP_opt.t5_Mode_CellTrack = 2 ;               
                if APP_opt.START_t5 == 1 
                    ReFresh_Frame;
                end
            end
        end

        % Button pushed function: t5_B_Save_Det
        function t5_B_Save_Det_ButtonPushed(app, event)
            global APP_opt ;
            global oDet ; 
            filename = strsplit(APP_opt.t5_fileName_Det, {'.'});
            filename = [filename{1} '_Updated.' filename{2}] ;            
            save([ APP_opt.t5_path_Det  filename] , '-struct', 'oDet' );
        end

        % Value changed function: t5_showPole_CheckBox
        function t5_showPole_CheckBox_ValueChanged(app, event)
            global APP_opt ;
            value = app.t5_showPole_CheckBox.Value;
            if value == 0
                APP_opt.t5_display_Pole = 0;    % Do not display pole mark
            elseif value == 1
                APP_opt.t5_display_Pole = 1;    % Display pole mark
            end
            
            if APP_opt.START_t5 == 1       % only if we are actively tracking
                % REFRESH the frame and update displayed options
                ReFresh_Frame;
            end 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Tab = uitabgroup(app.UIFigure);
            app.Analysis_Tab = uitab(app.Tab);
            app.Analysis_Tab.Title = 'Analyse Fluorescence';
            app.Analysis_Tab.Units = 'pixels';
            app.BFstackLabel = uilabel(app.Analysis_Tab);
            app.BFstackLabel.Text = 'BF stack';
            app.t1_Edit_BF_Fold = uieditfield(app.Analysis_Tab, 'text');
            app.t1_Edit_BF_Fold.Enable = 'off';
            app.t1_Edit_BF_Fold.Editable = 'off';
            app.Ch1stackLabel = uilabel(app.Analysis_Tab);
            app.Ch1stackLabel.Text = 'Ch. 1 stack';
            app.t1_Load_BF = uibutton(app.Analysis_Tab, 'push');
            app.t1_Load_BF.ButtonPushedFcn = createCallbackFcn(app, @t1_Load_BF_ButtonPushed, true);
            app.t1_Load_BF.Enable = 'off';
            app.t1_Load_BF.Text = 'Load...';
            app.t1_Alg_Panel = uipanel(app.Analysis_Tab);
            app.t1_Alg_Panel.Title = 'Algorithm';
            app.t1_Alg_Radio = uibuttongroup(app.t1_Alg_Panel);
            app.t1_Alg_Radio.SelectionChangedFcn = createCallbackFcn(app, @t1_Alg_Radio_SelectionChanged, true);
            app.Memb_2_PoleButton = uiradiobutton(app.t1_Alg_Radio);
            app.Memb_2_PoleButton.Text = 'Memb_2_Pole';
            app.Polar_SigButton = uiradiobutton(app.t1_Alg_Radio);
            app.Polar_SigButton.Text = 'Polar_Sig';
            app.AvgSignalButton = uiradiobutton(app.t1_Alg_Radio);
            app.AvgSignalButton.Text = 'Avg Signal';
            app.AvgSignalButton.Value = true;
            app.LateralMembsizeLabel = uilabel(app.t1_Alg_Panel);
            app.LateralMembsizeLabel.Text = 'Lateral Memb. size:';
            app.t1_Edit_M2P_LatMemb = uieditfield(app.t1_Alg_Panel, 'numeric');
            app.t1_Edit_M2P_LatMemb.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_M2P_LatMemb_ValueChanged, true);
            app.t1_Edit_M2P_LatMemb.Limits = [1 Inf];
            app.t1_Edit_M2P_LatMemb.Enable = 'off';
            app.t1_Edit_M2P_LatMemb.Value = 3;
            app.MembsizeLabel = uilabel(app.t1_Alg_Panel);
            app.MembsizeLabel.Text = 'Memb. size:';
            app.t1_Edit_PL_Memb = uieditfield(app.t1_Alg_Panel, 'numeric');
            app.t1_Edit_PL_Memb.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_PL_Memb_ValueChanged, true);
            app.t1_Edit_PL_Memb.Limits = [0 Inf];
            app.t1_Edit_PL_Memb.Enable = 'off';
            app.t1_Edit_PL_Memb.Value = 3;
            app.PoleMembsizeLabel_2 = uilabel(app.t1_Alg_Panel);
            app.PoleMembsizeLabel_2.Text = 'Pole Memb. size:';
            app.t1_Edit_PL_PoleMemb = uieditfield(app.t1_Alg_Panel, 'numeric');
            app.t1_Edit_PL_PoleMemb.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_PL_PoleMemb_ValueChanged, true);
            app.t1_Edit_PL_PoleMemb.Limits = [0 Inf];
            app.t1_Edit_PL_PoleMemb.Enable = 'off';
            app.t1_Edit_PL_PoleMemb.Value = 5;
            app.RFactorLabel = uilabel(app.t1_Alg_Panel);
            app.RFactorLabel.Text = 'R Factor';
            app.t1_Edit_PL_PoleRad = uieditfield(app.t1_Alg_Panel, 'numeric');
            app.t1_Edit_PL_PoleRad.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_PL_PoleRad_ValueChanged, true);
            app.t1_Edit_PL_PoleRad.Limits = [0 Inf];
            app.t1_Edit_PL_PoleRad.Enable = 'off';
            app.t1_Edit_PL_PoleRad.Value = 2.5;
            app.t1_Check_AxisProfile = uicheckbox(app.t1_Alg_Panel);
            app.t1_Check_AxisProfile.ValueChangedFcn = createCallbackFcn(app, @t1_Check_AxisProfile_ValueChanged, true);
            app.t1_Check_AxisProfile.Text = 'Axial profile [px]';
            app.PoleMembsizeLabel = uilabel(app.t1_Alg_Panel);
            app.PoleMembsizeLabel.Text = 'Pole Memb. size:';
            app.t1_Edit_M2P_PoleMemb = uieditfield(app.t1_Alg_Panel, 'numeric');
            app.t1_Edit_M2P_PoleMemb.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_M2P_PoleMemb_ValueChanged, true);
            app.t1_Edit_M2P_PoleMemb.Limits = [1 Inf];
            app.t1_Edit_M2P_PoleMemb.Enable = 'off';
            app.t1_Edit_M2P_PoleMemb.Value = 3;
            app.PoleRadiusLabel = uilabel(app.t1_Alg_Panel);
            app.PoleRadiusLabel.Text = 'Pole Radius:';
            app.t1_Edit_M2P_PoleRad = uieditfield(app.t1_Alg_Panel, 'numeric');
            app.t1_Edit_M2P_PoleRad.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_M2P_PoleRad_ValueChanged, true);
            app.t1_Edit_M2P_PoleRad.Limits = [1 Inf];
            app.t1_Edit_M2P_PoleRad.Enable = 'off';
            app.t1_Edit_M2P_PoleRad.Value = 10;
            app.ScaleFactorLabel = uilabel(app.t1_Alg_Panel);
            app.ScaleFactorLabel.Text = 'Scale Factor';
            app.t1_Edit_PL_ScaleFact = uieditfield(app.t1_Alg_Panel, 'numeric');
            app.t1_Edit_PL_ScaleFact.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_PL_ScaleFact_ValueChanged, true);
            app.t1_Edit_PL_ScaleFact.Limits = [1 Inf];
            app.t1_Edit_PL_ScaleFact.Enable = 'off';
            app.t1_Edit_PL_ScaleFact.Value = 1;
            app.t1_Edit_AxisWidth = uieditfield(app.t1_Alg_Panel, 'numeric');
            app.t1_Edit_AxisWidth.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_AxisWidth_ValueChanged, true);
            app.t1_Edit_AxisWidth.Limits = [0 100];
            app.t1_Edit_AxisWidth.ValueDisplayFormat = '%11.3g';
            app.t1_Edit_AxisWidth.Value = 2;
            app.t1_Check_Segmentation = uicheckbox(app.t1_Alg_Panel);
            app.t1_Check_Segmentation.ValueChangedFcn = createCallbackFcn(app, @t1_Check_Segmentation_ValueChanged, true);
            app.t1_Check_Segmentation.Text = 'Segmentation [px]';
            app.t1_Edit_SegmentWidth = uieditfield(app.t1_Alg_Panel, 'numeric');
            app.t1_Edit_SegmentWidth.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_SegmentWidth_ValueChanged, true);
            app.t1_Edit_SegmentWidth.Limits = [2 100];
            app.t1_Edit_SegmentWidth.ValueDisplayFormat = '%11.3g';
            app.t1_Edit_SegmentWidth.Value = 5;
            app.t1_Label_PerimSpacing = uilabel(app.t1_Alg_Panel);
            app.t1_Label_PerimSpacing.Enable = 'off';
            app.t1_Label_PerimSpacing.Text = 'Sampling points spacing';
            app.t1_Edit_PerimeterSpacing = uieditfield(app.t1_Alg_Panel, 'numeric');
            app.t1_Edit_PerimeterSpacing.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_PerimeterSpacing_ValueChanged, true);
            app.t1_Edit_PerimeterSpacing.Limits = [0.25 1];
            app.t1_Edit_PerimeterSpacing.ValueDisplayFormat = '%11.2g';
            app.t1_Edit_PerimeterSpacing.Enable = 'off';
            app.t1_Edit_PerimeterSpacing.Value = 0.7;
            app.t1_Edit_PerimeterWidth = uieditfield(app.t1_Alg_Panel, 'numeric');
            app.t1_Edit_PerimeterWidth.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_PerimeterWidth_ValueChanged, true);
            app.t1_Edit_PerimeterWidth.Limits = [0 100];
            app.t1_Edit_PerimeterWidth.ValueDisplayFormat = '%11.3g';
            app.t1_Edit_PerimeterWidth.Value = 4;
            app.t1_Check_PerimeterSig = uicheckbox(app.t1_Alg_Panel);
            app.t1_Check_PerimeterSig.ValueChangedFcn = createCallbackFcn(app, @t1_Check_PerimeterSig_ValueChanged, true);
            app.t1_Check_PerimeterSig.Text = 'Perim. profile [px]';
            app.t1_ProfileRadioGroup = uibuttongroup(app.t1_Alg_Panel);
            app.t1_ProfileRadioGroup.SelectionChangedFcn = createCallbackFcn(app, @t1_ProfileRadioGroup_SelectionChanged, true);
            app.meanvalue_Radio = uiradiobutton(app.t1_ProfileRadioGroup);
            app.meanvalue_Radio.Enable = 'off';
            app.meanvalue_Radio.Text = 'mean value';
            app.meanvalue_Radio.Value = true;
            app.maxvalue_Radio = uiradiobutton(app.t1_ProfileRadioGroup);
            app.maxvalue_Radio.Enable = 'off';
            app.maxvalue_Radio.Text = 'max value';
            app.START_t1 = uibutton(app.Analysis_Tab, 'push');
            app.START_t1.ButtonPushedFcn = createCallbackFcn(app, @START_t1_ButtonPushed, true);
            app.START_t1.Text = 'START';
            app.STOP_t1 = uibutton(app.Analysis_Tab, 'push');
            app.STOP_t1.ButtonPushedFcn = createCallbackFcn(app, @STOP_t1_ButtonPushed, true);
            app.STOP_t1.Text = 'STOP';
            app.BoxborderpxEditFieldLabel = uilabel(app.Analysis_Tab);
            app.BoxborderpxEditFieldLabel.Text = 'Box border [px]:';
            app.t1_Edit_BorderBox = uieditfield(app.Analysis_Tab, 'numeric');
            app.t1_Edit_BorderBox.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_BorderBox_ValueChanged, true);
            app.t1_Edit_BorderBox.Limits = [5 Inf];
            app.t1_Edit_BorderBox.Value = 10;
            app.t1_ShowPlot = uicheckbox(app.Analysis_Tab);
            app.t1_ShowPlot.ValueChangedFcn = createCallbackFcn(app, @t1_ShowPlot_ValueChanged, true);
            app.t1_ShowPlot.Text = 'Display analysis for             sec.';
            app.SaveasLabel = uilabel(app.Analysis_Tab);
            app.SaveasLabel.Text = 'Save as:';
            app.t1_Exp_Title = uieditfield(app.Analysis_Tab, 'text');
            app.t1_Exp_Title.ValueChangedFcn = createCallbackFcn(app, @t1_Exp_Title_ValueChanged, true);
            app.t1_Edit_WaitPlot = uieditfield(app.Analysis_Tab, 'numeric');
            app.t1_Edit_WaitPlot.ValueChangedFcn = createCallbackFcn(app, @t1_Edit_WaitPlot_ValueChanged, true);
            app.t1_Edit_WaitPlot.ValueDisplayFormat = '%11.3g';
            app.t1_Edit_WaitPlot.Value = 2;
            app.Ch2stackLabel = uilabel(app.Analysis_Tab);
            app.Ch2stackLabel.Text = 'Ch. 2 stack';
            app.t1_Edit_CH2_Fold = uieditfield(app.Analysis_Tab, 'text');
            app.t1_Edit_CH2_Fold.Enable = 'off';
            app.t1_Edit_CH2_Fold.Editable = 'off';
            app.t1_Edit_CH1_Fold = uieditfield(app.Analysis_Tab, 'text');
            app.t1_Edit_CH1_Fold.Editable = 'off';
            app.t1_Load_CH2 = uibutton(app.Analysis_Tab, 'push');
            app.t1_Load_CH2.ButtonPushedFcn = createCallbackFcn(app, @t1_Load_CH2_ButtonPushed, true);
            app.t1_Load_CH2.Enable = 'off';
            app.t1_Load_CH2.Text = 'Load...';
            app.t1_Load_CH1 = uibutton(app.Analysis_Tab, 'push');
            app.t1_Load_CH1.ButtonPushedFcn = createCallbackFcn(app, @t1_Load_CH1_ButtonPushed, true);
            app.t1_Load_CH1.Text = 'Load...';
            app.t1_CheckBox_CH2 = uicheckbox(app.Analysis_Tab);
            app.t1_CheckBox_CH2.ValueChangedFcn = createCallbackFcn(app, @t1_CheckBox_CH2_ValueChanged, true);
            app.t1_CheckBox_CH2.Text = '';
            app.t1_Check_CH3_Marker = uicheckbox(app.Analysis_Tab);
            app.t1_Check_CH3_Marker.ValueChangedFcn = createCallbackFcn(app, @t1_Check_CH3_Marker_ValueChanged, true);
            app.t1_Check_CH3_Marker.Enable = 'off';
            app.t1_Check_CH3_Marker.Text = 'Ch. 3 marker';
            app.t1_Load_Det = uibutton(app.Analysis_Tab, 'push');
            app.t1_Load_Det.ButtonPushedFcn = createCallbackFcn(app, @t1_Load_Det_ButtonPushed, true);
            app.t1_Load_Det.Text = 'Load...';
            app.DetmatLabel_2 = uilabel(app.Analysis_Tab);
            app.DetmatLabel_2.Text = 'Det.mat';
            app.t1_Edit_Det_file = uieditfield(app.Analysis_Tab, 'text');
            app.t1_Edit_Det_file.Editable = 'off';
            app.t1_Load_Res_D2T = uibutton(app.Analysis_Tab, 'push');
            app.t1_Load_Res_D2T.ButtonPushedFcn = createCallbackFcn(app, @t1_Load_Res_D2T_ButtonPushed, true);
            app.t1_Load_Res_D2T.Enable = 'off';
            app.t1_Load_Res_D2T.Text = 'Load...';
            app.Res_D2TmatLabel = uilabel(app.Analysis_Tab);
            app.Res_D2TmatLabel.Text = 'Res_D2T.mat';
            app.t1_Edit_Res_D2T = uieditfield(app.Analysis_Tab, 'text');
            app.t1_Edit_Res_D2T.Enable = 'off';
            app.t1_Edit_Res_D2T.Editable = 'off';
            app.t1_Create_cloneList = uibutton(app.Analysis_Tab, 'push');
            app.t1_Create_cloneList.ButtonPushedFcn = createCallbackFcn(app, @t1_Create_cloneList_ButtonPushed, true);
            app.t1_Create_cloneList.Enable = 'off';
            app.t1_Create_cloneList.Text = 'Create';
            app.t1_CheckBox_CreateCloneList = uicheckbox(app.Analysis_Tab);
            app.t1_CheckBox_CreateCloneList.ValueChangedFcn = createCallbackFcn(app, @t1_CheckBox_CreateCloneList_ValueChanged, true);
            app.t1_CheckBox_CreateCloneList.Text = 'Create cloneList.mat:';
            app.t1_CheckBox_CH3 = uicheckbox(app.Analysis_Tab);
            app.t1_CheckBox_CH3.ValueChangedFcn = createCallbackFcn(app, @t1_CheckBox_CH3_ValueChanged, true);
            app.t1_CheckBox_CH3.Text = '';
            app.Ch3stackLabel = uilabel(app.Analysis_Tab);
            app.Ch3stackLabel.Text = 'Ch. 3 stack';
            app.t1_Edit_CH3_Fold = uieditfield(app.Analysis_Tab, 'text');
            app.t1_Edit_CH3_Fold.Enable = 'off';
            app.t1_Edit_CH3_Fold.Editable = 'off';
            app.t1_Load_CH3 = uibutton(app.Analysis_Tab, 'push');
            app.t1_Load_CH3.ButtonPushedFcn = createCallbackFcn(app, @t1_Load_CH3_ButtonPushed, true);
            app.t1_Load_CH3.Enable = 'off';
            app.t1_Load_CH3.Text = 'Load...';
            app.t1_CheckBox_BF = uicheckbox(app.Analysis_Tab);
            app.t1_CheckBox_BF.ValueChangedFcn = createCallbackFcn(app, @t1_CheckBox_BF_ValueChanged, true);
            app.t1_CheckBox_BF.Text = '';
            app.Lineage_Tab = uitab(app.Tab);
            app.Lineage_Tab.Title = 'Lineage Plotting';
            app.Lineage_Tab.Units = 'pixels';
            app.t3_Load_cloneList = uibutton(app.Lineage_Tab, 'push');
            app.t3_Load_cloneList.ButtonPushedFcn = createCallbackFcn(app, @t3_Load_cloneList_ButtonPushed, true);
            app.t3_Load_cloneList.Text = 'Load...';
            app.clonesmatfileLabel = uilabel(app.Lineage_Tab);
            app.clonesmatfileLabel.Text = 'clones.mat file';
            app.t3_Edit_cloneList = uieditfield(app.Lineage_Tab, 'text');
            app.t3_Edit_cloneList.Editable = 'off';
            app.PLOT_t3 = uibutton(app.Lineage_Tab, 'push');
            app.PLOT_t3.ButtonPushedFcn = createCallbackFcn(app, @PLOT_t3_ButtonPushed, true);
            app.PLOT_t3.Enable = 'off';
            app.PLOT_t3.Text = 'PLOT';
            app.SizeborderlineLabel = uilabel(app.Lineage_Tab);
            app.SizeborderlineLabel.Text = 'Size border line';
            app.t3_EditField_Borderline = uieditfield(app.Lineage_Tab, 'numeric');
            app.t3_EditField_Borderline.ValueChangedFcn = createCallbackFcn(app, @t3_EditField_Borderline_ValueChanged, true);
            app.t3_EditField_Borderline.Value = 1.5;
            app.LinethicknessLabel = uilabel(app.Lineage_Tab);
            app.LinethicknessLabel.Text = 'Line thickness';
            app.t3_EditField_LineSize = uieditfield(app.Lineage_Tab, 'numeric');
            app.t3_EditField_LineSize.ValueChangedFcn = createCallbackFcn(app, @t3_EditField_LineSize_ValueChanged, true);
            app.t3_EditField_LineSize.Value = 10;
            app.DistancebtwlinesLabel = uilabel(app.Lineage_Tab);
            app.DistancebtwlinesLabel.Text = 'Distance btw lines';
            app.t3_EditField_LinesDistance = uieditfield(app.Lineage_Tab, 'numeric');
            app.t3_EditField_LinesDistance.ValueChangedFcn = createCallbackFcn(app, @t3_EditField_LinesDistance_ValueChanged, true);
            app.t3_EditField_LinesDistance.Value = 25;
            app.t3_CheckBox_DisplayPoles = uicheckbox(app.Lineage_Tab);
            app.t3_CheckBox_DisplayPoles.ValueChangedFcn = createCallbackFcn(app, @t3_CheckBox_DisplayPoles_ValueChanged, true);
            app.t3_CheckBox_DisplayPoles.Text = 'Show Poles';
            app.t3_CheckBox_DisplayPoles.Value = true;
            app.t3_DropDown_LUT_CH2 = uidropdown(app.Lineage_Tab);
            app.t3_DropDown_LUT_CH2.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.t3_DropDown_LUT_CH2.ValueChangedFcn = createCallbackFcn(app, @t3_DropDown_LUT_CH2_ValueChanged, true);
            app.t3_DropDown_LUT_CH2.Value = '1';
            app.frameXminLabel = uilabel(app.Lineage_Tab);
            app.frameXminLabel.Text = '1 frame = X min';
            app.t3_EditField_fpmCH1 = uieditfield(app.Lineage_Tab, 'numeric');
            app.t3_EditField_fpmCH1.ValueChangedFcn = createCallbackFcn(app, @t3_EditField_fpmCH1_ValueChanged, true);
            app.t3_EditField_fpmCH1.Limits = [1 Inf];
            app.t3_EditField_fpmCH1.Value = 1;
            app.ValuetouseforplottingLabel = uilabel(app.Lineage_Tab);
            app.ValuetouseforplottingLabel.Enable = 'off';
            app.ValuetouseforplottingLabel.Text = 'Value to use for plotting';
            app.t3_DropDownValue = uidropdown(app.Lineage_Tab);
            app.t3_DropDownValue.Items = {'1 - Whole Cell', '2 - Membrane', '3 - Old_pole', '4 - New_pole'};
            app.t3_DropDownValue.ValueChangedFcn = createCallbackFcn(app, @t3_DropDownValue_ValueChanged, true);
            app.t3_DropDownValue.Enable = 'off';
            app.t3_DropDownValue.Value = '1 - Whole Cell';
            app.t3_CheckBox_DisplayExI = uicheckbox(app.Lineage_Tab);
            app.t3_CheckBox_DisplayExI.ValueChangedFcn = createCallbackFcn(app, @t3_CheckBox_DisplayExI_ValueChanged, true);
            app.t3_CheckBox_DisplayExI.Text = 'Show Extra info';
            app.ValueRangeCh1Label = uilabel(app.Lineage_Tab);
            app.ValueRangeCh1Label.Text = 'Value Range Ch. 1';
            app.t3_ValueRange_CH1 = uieditfield(app.Lineage_Tab, 'text');
            app.t3_ValueRange_CH1.ValueChangedFcn = createCallbackFcn(app, @t3_ValueRange_CH1_ValueChanged, true);
            app.t3_ValueRange_CH1.Value = 'min:max';
            app.SegmentsizeEditFieldLabel = uilabel(app.Lineage_Tab);
            app.SegmentsizeEditFieldLabel.Text = 'Segment size';
            app.t3_EditField_SegmentSize = uieditfield(app.Lineage_Tab, 'numeric');
            app.t3_EditField_SegmentSize.ValueChangedFcn = createCallbackFcn(app, @t3_EditField_SegmentSize_ValueChanged, true);
            app.t3_EditField_SegmentSize.Limits = [1 Inf];
            app.t3_EditField_SegmentSize.Value = 1;
            app.t3_Save_txt_CheckBox = uicheckbox(app.Lineage_Tab);
            app.t3_Save_txt_CheckBox.ValueChangedFcn = createCallbackFcn(app, @t3_Save_txt_CheckBox_ValueChanged, true);
            app.t3_Save_txt_CheckBox.Text = '';
            app.t3_DisplayID_Radio = uibuttongroup(app.Lineage_Tab);
            app.t3_DisplayID_Radio.SelectionChangedFcn = createCallbackFcn(app, @t3_DisplayID_Radio_SelectionChanged, true);
            app.t3_DisplayID_Radio.Title = 'show ID in plot:';
            app.noneButton_3 = uiradiobutton(app.t3_DisplayID_Radio);
            app.noneButton_3.Text = 'none';
            app.noneButton_3.Value = true;
            app.lineageButton = uiradiobutton(app.t3_DisplayID_Radio);
            app.lineageButton.Text = 'lineage';
            app.manualtrackButton = uiradiobutton(app.t3_DisplayID_Radio);
            app.manualtrackButton.Text = 'manual track';
            app.t3_Load_cloneFolder = uibutton(app.Lineage_Tab, 'push');
            app.t3_Load_cloneFolder.ButtonPushedFcn = createCallbackFcn(app, @t3_Load_cloneFolder_ButtonPushed, true);
            app.t3_Load_cloneFolder.Enable = 'off';
            app.t3_Load_cloneFolder.Text = 'Load...';
            app.loadFolderLabel = uilabel(app.Lineage_Tab);
            app.loadFolderLabel.Enable = 'off';
            app.loadFolderLabel.Text = 'load Folder';
            app.t3_Edit_cloneFolder = uieditfield(app.Lineage_Tab, 'text');
            app.t3_Edit_cloneFolder.Enable = 'off';
            app.t3_Edit_cloneFolder.Editable = 'off';
            app.t3_TypeAnalysis_Radio = uibuttongroup(app.Lineage_Tab);
            app.t3_TypeAnalysis_Radio.SelectionChangedFcn = createCallbackFcn(app, @t3_TypeAnalysis_Radio_SelectionChanged, true);
            app.t3_TypeAnalysis_Radio.Title = 'Analyse:';
            app.IntergenerationalButton = uiradiobutton(app.t3_TypeAnalysis_Radio);
            app.IntergenerationalButton.Text = 'Intergenerational';
            app.SingleLineageButton = uiradiobutton(app.t3_TypeAnalysis_Radio);
            app.SingleLineageButton.Text = 'Single Lineage';
            app.SingleLineageButton.Value = true;
            app.FounderCellLabel = uilabel(app.Lineage_Tab);
            app.FounderCellLabel.Text = 'Founder Cell';
            app.t3_FounderCell_DropDown = uidropdown(app.Lineage_Tab);
            app.t3_FounderCell_DropDown.Items = {};
            app.t3_FounderCell_DropDown.ValueChangedFcn = createCallbackFcn(app, @t3_FounderCell_DropDown_ValueChanged, true);
            app.t3_FounderCell_DropDown.Value = {};
            app.saveplotteddataastxtfileLabel = uilabel(app.Lineage_Tab);
            app.saveplotteddataastxtfileLabel.Text = 'save plotted data (*) as .txt file';
            app.t3_Exp_Title = uieditfield(app.Lineage_Tab, 'text');
            app.t3_Exp_Title.ValueChangedFcn = createCallbackFcn(app, @t3_Exp_Title_ValueChanged, true);
            app.t3_Exp_Title.Enable = 'off';
            app.t3_AutoSave_Radio = uibuttongroup(app.Lineage_Tab);
            app.t3_AutoSave_Radio.SelectionChangedFcn = createCallbackFcn(app, @t3_AutoSave_Radio_SelectionChanged, true);
            app.t3_AutoSave_Radio.Title = 'Autosave plot(s):';
            app.NoButton = uiradiobutton(app.t3_AutoSave_Radio);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.PDFButton = uiradiobutton(app.t3_AutoSave_Radio);
            app.PDFButton.Text = '.PDF';
            app.TIFFButton = uiradiobutton(app.t3_AutoSave_Radio);
            app.TIFFButton.Text = '.TIFF';
            app.SVGButton = uiradiobutton(app.t3_AutoSave_Radio);
            app.SVGButton.Text = '.SVG';
            app.Label_Asterix2 = uilabel(app.Lineage_Tab);
            app.Label_Asterix2.Text = '*';
            app.Label_Asterix1 = uilabel(app.Lineage_Tab);
            app.Label_Asterix1.Text = '*';
            app.t3_Display_Plot = uicheckbox(app.Lineage_Tab);
            app.t3_Display_Plot.ValueChangedFcn = createCallbackFcn(app, @t3_Display_Plot_ValueChanged, true);
            app.t3_Display_Plot.Text = 'Display plot(s)';
            app.t3_Display_Plot.Value = true;
            app.t3_Summary_Plot = uicheckbox(app.Lineage_Tab);
            app.t3_Summary_Plot.ValueChangedFcn = createCallbackFcn(app, @t3_Summary_Plot_ValueChanged, true);
            app.t3_Summary_Plot.Enable = 'off';
            app.t3_Summary_Plot.Text = 'Summary plot';
            app.t3_RadioBox_PlotType = uibuttongroup(app.Lineage_Tab);
            app.t3_RadioBox_PlotType.SelectionChangedFcn = createCallbackFcn(app, @t3_RadioBox_PlotType_SelectionChanged, true);
            app.t3_RadioBox_PlotType.Title = 'Plot Type';
            app.LineageTreeButton = uiradiobutton(app.t3_RadioBox_PlotType);
            app.LineageTreeButton.Enable = 'off';
            app.LineageTreeButton.Text = '1 - Lineage Tree';
            app.LineageTreeButton.Value = true;
            app.IndividualClonesButton = uiradiobutton(app.t3_RadioBox_PlotType);
            app.IndividualClonesButton.Enable = 'off';
            app.IndividualClonesButton.Text = '2 - Individual Clones';
            app.ManualTracksButton = uiradiobutton(app.t3_RadioBox_PlotType);
            app.ManualTracksButton.Enable = 'off';
            app.ManualTracksButton.Text = '3 - Manual Tracks';
            app.t3_RadioBox_LineType = uibuttongroup(app.Lineage_Tab);
            app.t3_RadioBox_LineType.SelectionChangedFcn = createCallbackFcn(app, @t3_RadioBox_LineType_SelectionChanged, true);
            app.t3_RadioBox_LineType.Title = 'Line Type';
            app.singlevalueButton = uiradiobutton(app.t3_RadioBox_LineType);
            app.singlevalueButton.Enable = 'off';
            app.singlevalueButton.Text = '1 - single value';
            app.singlevalueButton.Value = true;
            app.bivalueButton = uiradiobutton(app.t3_RadioBox_LineType);
            app.bivalueButton.Enable = 'off';
            app.bivalueButton.Text = '2 - bi-value';
            app.singlevalueRatioButton = uiradiobutton(app.t3_RadioBox_LineType);
            app.singlevalueRatioButton.Enable = 'off';
            app.singlevalueRatioButton.Text = '3 - single value Ratio';
            app.segmentationButton = uiradiobutton(app.t3_RadioBox_LineType);
            app.segmentationButton.Enable = 'off';
            app.segmentationButton.Text = '4 - segmentation';
            app.t3_PlotHystogram = uibutton(app.Lineage_Tab, 'push');
            app.t3_PlotHystogram.ButtonPushedFcn = createCallbackFcn(app, @t3_PlotHystogram_ButtonPushed, true);
            app.t3_PlotHystogram.Enable = 'off';
            app.t3_PlotHystogram.Text = 'Plot Hystogram';
            app.t3_PlotChannels_Radio = uibuttongroup(app.Lineage_Tab);
            app.t3_PlotChannels_Radio.SelectionChangedFcn = createCallbackFcn(app, @t3_PlotChannels_Radio_SelectionChanged, true);
            app.ch_1 = uiradiobutton(app.t3_PlotChannels_Radio);
            app.ch_1.Text = '1';
            app.ch_1.Value = true;
            app.ch_2 = uiradiobutton(app.t3_PlotChannels_Radio);
            app.ch_2.Text = '2';
            app.both_ch = uiradiobutton(app.t3_PlotChannels_Radio);
            app.both_ch.Text = 'both 1 and 2';
            app.ratio12Button = uiradiobutton(app.t3_PlotChannels_Radio);
            app.ratio12Button.Text = 'ratio 1/2';
            app.ratio21Button = uiradiobutton(app.t3_PlotChannels_Radio);
            app.ratio21Button.Text = 'ratio 2/1';
            app.PlotFluorescentChannelsLabel = uilabel(app.Lineage_Tab);
            app.PlotFluorescentChannelsLabel.Text = 'Plot Fluorescent Channel(s):';
            app.LUTColormapLabel = uilabel(app.Lineage_Tab);
            app.LUTColormapLabel.Text = 'LUT Colormap';
            app.t3_DropDown_LUT_CH1 = uidropdown(app.Lineage_Tab);
            app.t3_DropDown_LUT_CH1.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.t3_DropDown_LUT_CH1.ValueChangedFcn = createCallbackFcn(app, @t3_DropDown_LUT_CH1_ValueChanged, true);
            app.t3_DropDown_LUT_CH1.Value = '1';
            app.Ch1Label = uilabel(app.Lineage_Tab);
            app.Ch1Label.Text = 'Ch. 1';
            app.Ch2Label = uilabel(app.Lineage_Tab);
            app.Ch2Label.Text = 'Ch. 2';
            app.ValueRangeCh2Label = uilabel(app.Lineage_Tab);
            app.ValueRangeCh2Label.Text = 'Value Range Ch. 2';
            app.t3_ValueRange_CH2 = uieditfield(app.Lineage_Tab, 'text');
            app.t3_ValueRange_CH2.Value = 'min:max';
            app.t3_NormalizeColormap = uibuttongroup(app.Lineage_Tab);
            app.t3_NormalizeColormap.SelectionChangedFcn = createCallbackFcn(app, @t3_NormalizeColormap_SelectionChanged, true);
            app.t3_NormalizeColormap.Title = 'Normalize Colormap';
            app.CMap_Lineage = uiradiobutton(app.t3_NormalizeColormap);
            app.CMap_Lineage.Enable = 'off';
            app.CMap_Lineage.Text = 'Lineage';
            app.CMap_Lineage.Value = true;
            app.CMap_Generation = uiradiobutton(app.t3_NormalizeColormap);
            app.CMap_Generation.Enable = 'off';
            app.CMap_Generation.Text = 'Generation';
            app.Manual_Tracking_Tab = uitab(app.Tab);
            app.Manual_Tracking_Tab.Title = 'Manual Tracking';
            app.Manual_Tracking_Tab.Units = 'pixels';
            app.START_t5 = uibutton(app.Manual_Tracking_Tab, 'push');
            app.START_t5.ButtonPushedFcn = createCallbackFcn(app, @START_t5_ButtonPushed, true);
            app.START_t5.Text = 'START';
            app.STOP_t5 = uibutton(app.Manual_Tracking_Tab, 'push');
            app.STOP_t5.ButtonPushedFcn = createCallbackFcn(app, @STOP_t5_ButtonPushed, true);
            app.STOP_t5.Text = 'STOP';
            app.StackLabel = uilabel(app.Manual_Tracking_Tab);
            app.StackLabel.Text = 'Stack';
            app.t5_Edit_BF_Fold = uieditfield(app.Manual_Tracking_Tab, 'text');
            app.DetmatLabel = uilabel(app.Manual_Tracking_Tab);
            app.DetmatLabel.Text = 'Det.mat';
            app.t5_Edit_Det_File = uieditfield(app.Manual_Tracking_Tab, 'text');
            app.t5_Edit_Det_File.Enable = 'off';
            app.t5_Load_BF = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_Load_BF.ButtonPushedFcn = createCallbackFcn(app, @t5_Load_BF_ButtonPushed, true);
            app.t5_Load_BF.Text = 'Load...';
            app.t5_Load_Det = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_Load_Det.ButtonPushedFcn = createCallbackFcn(app, @t5_Load_Det_ButtonPushed, true);
            app.t5_Load_Det.Enable = 'off';
            app.t5_Load_Det.Text = 'Load...';
            app.t5_choose_Outline = uicheckbox(app.Manual_Tracking_Tab);
            app.t5_choose_Outline.ValueChangedFcn = createCallbackFcn(app, @t5_choose_Outline_ValueChanged, true);
            app.t5_choose_Outline.Text = '';
            app.t5_ff_forw_x1 = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_ff_forw_x1.ButtonPushedFcn = createCallbackFcn(app, @t5_ff_forw_x1_ButtonPushed, true);
            app.t5_ff_forw_x1.Text = '>';
            app.t5_ff_back_x1 = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_ff_back_x1.ButtonPushedFcn = createCallbackFcn(app, @t5_ff_back_x1_ButtonPushed, true);
            app.t5_ff_back_x1.Text = '<';
            app.t5_ff_forw_x10 = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_ff_forw_x10.ButtonPushedFcn = createCallbackFcn(app, @t5_ff_forw_x10ButtonPushed, true);
            app.t5_ff_forw_x10.Text = '>>';
            app.t5_ff_back_x10 = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_ff_back_x10.ButtonPushedFcn = createCallbackFcn(app, @t5_ff_back_x10_ButtonPushed, true);
            app.t5_ff_back_x10.Text = '<<';
            app.t5_Edit_Frame = uieditfield(app.Manual_Tracking_Tab, 'numeric');
            app.t5_Edit_Frame.ValueChangedFcn = createCallbackFcn(app, @t5_Edit_Frame_ValueChanged, true);
            app.t5_Edit_Frame.Limits = [1 Inf];
            app.t5_Edit_Frame.Value = 1;
            app.t5_Label_totFrames = uilabel(app.Manual_Tracking_Tab);
            app.t5_Label_totFrames.Text = '   ';
            app.t5_Load_Track = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_Load_Track.ButtonPushedFcn = createCallbackFcn(app, @t5_Load_Track_ButtonPushed, true);
            app.t5_Load_Track.Enable = 'off';
            app.t5_Load_Track.Text = 'Load...';
            app.TracktxtLabel = uilabel(app.Manual_Tracking_Tab);
            app.TracktxtLabel.Text = 'Track.txt';
            app.t5_Edit_Track_File = uieditfield(app.Manual_Tracking_Tab, 'text');
            app.t5_Edit_Track_File.Enable = 'off';
            app.t5_B_Delete = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_B_Delete.ButtonPushedFcn = createCallbackFcn(app, @t5_B_DeleteButtonPushed, true);
            app.t5_B_Delete.Text = 'Delete';
            app.t5_B_Add = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_B_Add.ButtonPushedFcn = createCallbackFcn(app, @t5_B_Add_ButtonPushed, true);
            app.t5_B_Add.Text = 'Add';
            app.t5_B_Delete_All = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_B_Delete_All.ButtonPushedFcn = createCallbackFcn(app, @t5_B_Delete_All_ButtonPushed, true);
            app.t5_B_Delete_All.Text = 'Delete All';
            app.SelectTrackLabel = uilabel(app.Manual_Tracking_Tab);
            app.SelectTrackLabel.Text = 'Select Track';
            app.t5_SelectCellDropDown = uidropdown(app.Manual_Tracking_Tab);
            app.t5_SelectCellDropDown.Items = {};
            app.t5_SelectCellDropDown.ValueChangedFcn = createCallbackFcn(app, @t5_SelectCellDropDown_ValueChanged, true);
            app.t5_SelectCellDropDown.Value = {};
            app.t5_B_SaveTrack = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_B_SaveTrack.ButtonPushedFcn = createCallbackFcn(app, @t5_B_SaveTrack_ButtonPushed, true);
            app.t5_B_SaveTrack.Text = 'Save Tracks';
            app.t5_B_Clear = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_B_Clear.ButtonPushedFcn = createCallbackFcn(app, @t5_B_Clear_ButtonPushed, true);
            app.t5_B_Clear.Text = 'Clear';
            app.t5_ShowTracks_Radio = uibuttongroup(app.Manual_Tracking_Tab);
            app.t5_ShowTracks_Radio.SelectionChangedFcn = createCallbackFcn(app, @t5_ShowTracks_Radio_SelectionChanged, true);
            app.t5_ShowTracks_Radio.Title = 'Show Tracks:';
            app.noneButton = uiradiobutton(app.t5_ShowTracks_Radio);
            app.noneButton.Text = 'none';
            app.noneButton.Value = true;
            app.cellpreviousframeButton = uiradiobutton(app.t5_ShowTracks_Radio);
            app.cellpreviousframeButton.Text = '# cell: previous frame';
            app.cellonlypresentframeButton_2 = uiradiobutton(app.t5_ShowTracks_Radio);
            app.cellonlypresentframeButton_2.Text = '# cell: only present frame';
            app.celluptopresentframeButton_2 = uiradiobutton(app.t5_ShowTracks_Radio);
            app.celluptopresentframeButton_2.Text = '# cell: up to present frame';
            app.cellallframesButton_2 = uiradiobutton(app.t5_ShowTracks_Radio);
            app.cellallframesButton_2.Text = '# cell: all frames';
            app.allcellspreviousframeButton = uiradiobutton(app.t5_ShowTracks_Radio);
            app.allcellspreviousframeButton.Text = 'all cells: previous frame';
            app.allcellsonlypresentframeButton_2 = uiradiobutton(app.t5_ShowTracks_Radio);
            app.allcellsonlypresentframeButton_2.Text = 'all cells: only present frame';
            app.allcellsuptopresentframeButton_2 = uiradiobutton(app.t5_ShowTracks_Radio);
            app.allcellsuptopresentframeButton_2.Text = 'all cells: up to present frame';
            app.allcellsallframesButton_2 = uiradiobutton(app.t5_ShowTracks_Radio);
            app.allcellsallframesButton_2.Text = 'all cells: all frames';
            app.t5_showAid_CheckBox = uicheckbox(app.Manual_Tracking_Tab);
            app.t5_showAid_CheckBox.ValueChangedFcn = createCallbackFcn(app, @t5_showAid_CheckBox_ValueChanged, true);
            app.t5_showAid_CheckBox.Text = 'display aid';
            app.t5_choose_Track = uicheckbox(app.Manual_Tracking_Tab);
            app.t5_choose_Track.ValueChangedFcn = createCallbackFcn(app, @t5_choose_Track_ValueChanged, true);
            app.t5_choose_Track.Text = '';
            app.t5_TrackSize_ButtonGroup = uibuttongroup(app.Manual_Tracking_Tab);
            app.t5_TrackSize_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @t5_TrackSize_ButtonGroupSelectionChanged, true);
            app.t5_TrackSize_ButtonGroup.Title = 'Track Size:';
            app.smallButton = uiradiobutton(app.t5_TrackSize_ButtonGroup);
            app.smallButton.Text = 'small';
            app.smallButton.Value = true;
            app.mediumButton = uiradiobutton(app.t5_TrackSize_ButtonGroup);
            app.mediumButton.Text = 'medium';
            app.largeButton = uiradiobutton(app.t5_TrackSize_ButtonGroup);
            app.largeButton.Text = 'large';
            app.t5_CellOutline_ButtonGroup = uibuttongroup(app.Manual_Tracking_Tab);
            app.t5_CellOutline_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @t5_CellOutline_ButtonGroupSelectionChanged, true);
            app.t5_CellOutline_ButtonGroup.Title = 'Cell Outline:';
            app.noneButton_2 = uiradiobutton(app.t5_CellOutline_ButtonGroup);
            app.noneButton_2.Text = 'none';
            app.noneButton_2.Value = true;
            app.GreenButton = uiradiobutton(app.t5_CellOutline_ButtonGroup);
            app.GreenButton.Text = 'Green';
            app.AstrackButton = uiradiobutton(app.t5_CellOutline_ButtonGroup);
            app.AstrackButton.Text = 'As track';
            app.t1_Exp_Title_2Label = uilabel(app.Manual_Tracking_Tab);
            app.t1_Exp_Title_2Label.Text = 'Save as:';
            app.t5_TrackFile_Title = uieditfield(app.Manual_Tracking_Tab, 'text');
            app.t5_TrackFile_Title.ValueChangedFcn = createCallbackFcn(app, @t5_TrackFile_Title_ValueChanged, true);
            app.t5_B_CreateReport = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_B_CreateReport.ButtonPushedFcn = createCallbackFcn(app, @t5_B_CreateReport_ButtonPushed, true);
            app.t5_B_CreateReport.Text = 'Create';
            app.TrackingReport_Label = uilabel(app.Manual_Tracking_Tab);
            app.TrackingReport_Label.Text = 'Tracking Report:';
            app.t5_B_Combine_Det2Track = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_B_Combine_Det2Track.ButtonPushedFcn = createCallbackFcn(app, @t5_B_Combine_Det2Track_ButtonPushed, true);
            app.t5_B_Combine_Det2Track.Text = 'Combine';
            app.Combine_Label = uilabel(app.Manual_Tracking_Tab);
            app.Combine_Label.Text = 'Combine Det.mat and Track.txt:';
            app.t5_B_RemoveLast = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_B_RemoveLast.ButtonPushedFcn = createCallbackFcn(app, @t5_B_RemoveLast_ButtonPushed, true);
            app.t5_B_RemoveLast.Text = 'Remove last point(s)';
            app.t5_TrackingMode_Radio = uibuttongroup(app.Manual_Tracking_Tab);
            app.t5_TrackingMode_Radio.SelectionChangedFcn = createCallbackFcn(app, @t5_TrackingMode_Radio_SelectionChanged, true);
            app.t5_TrackingMode_Radio.Title = 'Tracking Mode';
            app.CellTrackingButton = uiradiobutton(app.t5_TrackingMode_Radio);
            app.CellTrackingButton.Text = 'Cell Tracking';
            app.CellTrackingButton.Value = true;
            app.PoleMarkingButton = uiradiobutton(app.t5_TrackingMode_Radio);
            app.PoleMarkingButton.Enable = 'off';
            app.PoleMarkingButton.Text = 'Pole Marking';
            app.t5_B_Save_Det = uibutton(app.Manual_Tracking_Tab, 'push');
            app.t5_B_Save_Det.ButtonPushedFcn = createCallbackFcn(app, @t5_B_Save_Det_ButtonPushed, true);
            app.t5_B_Save_Det.Enable = 'off';
            app.t5_B_Save_Det.Text = 'Save...';
            app.t5_showPole_CheckBox = uicheckbox(app.Manual_Tracking_Tab);
            app.t5_showPole_CheckBox.ValueChangedFcn = createCallbackFcn(app, @t5_showPole_CheckBox_ValueChanged, true);
            app.t5_showPole_CheckBox.Text = 'display pole';
            app.t5_Color_ButtonGroup = uibuttongroup(app.Manual_Tracking_Tab);
            app.t5_Color_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @t5_Color_ButtonGroupSelectionChanged, true);
            app.t5_Color_ButtonGroup.Title = 'Track Color:';
            app.RedButton = uiradiobutton(app.t5_Color_ButtonGroup);
            app.RedButton.Text = 'Red';
            app.RedButton.Value = true;
            app.YellowButton = uiradiobutton(app.t5_Color_ButtonGroup);
            app.YellowButton.Text = 'Yellow';
            app.TextOUT = uitextarea(app.UIFigure);
            app.TextOUT.Editable = 'off';
            app.TextOUT.Value = {''; '... IDLE ...'; ''};
        end
    end

    methods (Access = public)

        % Construct app
        function app = WHISIT()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end